classdef DAQDataAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LiveViewPanel                matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        PanelTraza                   matlab.ui.container.Panel
        StartTraceButton             matlab.ui.control.Button
        StopTraceButton              matlab.ui.control.StateButton
        IntTimemsEditFieldLabel      matlab.ui.control.Label
        IntTimemsEditField           matlab.ui.control.NumericEditField
        NBufferEditFieldLabel        matlab.ui.control.Label
        NBufferEditField             matlab.ui.control.NumericEditField
        NotifyScansSpinnerLabel      matlab.ui.control.Label
        NotifyScansSpinner           matlab.ui.control.Spinner
        MeanEditFieldLabel           matlab.ui.control.Label
        MeanEditField                matlab.ui.control.NumericEditField
        STDEditFieldLabel            matlab.ui.control.Label
        STDEditField                 matlab.ui.control.NumericEditField
        SavingDataSwitchLabel        matlab.ui.control.Label
        SavingDataSwitch             matlab.ui.control.Switch
        Panel4                       matlab.ui.container.Panel
        ScanButton                   matlab.ui.control.Button
        ScanSizeumEditFieldLabel     matlab.ui.control.Label
        ScanSizeumEditField          matlab.ui.control.NumericEditField
        ScanFreqEditFieldLabel       matlab.ui.control.Label
        ScanFreqEditField            matlab.ui.control.NumericEditField
        XoffsetEditFieldLabel        matlab.ui.control.Label
        XoffsetEditField             matlab.ui.control.NumericEditField
        YoffsetEditFieldLabel        matlab.ui.control.Label
        YoffsetEditField             matlab.ui.control.NumericEditField
        StopscanButton               matlab.ui.control.Button
        ScanNpixelsEditFieldLabel    matlab.ui.control.Label
        ScanNpixelsEditField         matlab.ui.control.NumericEditField
        CtimemsEditFieldLabel        matlab.ui.control.Label
        CtimemsEditField             matlab.ui.control.NumericEditField
        StimemsEditFieldLabel        matlab.ui.control.Label
        StimemsEditField             matlab.ui.control.NumericEditField
        SaveScanButton               matlab.ui.control.Button
        SampleNameEditFieldLabel     matlab.ui.control.Label
        SampleNameEditField          matlab.ui.control.EditField
        FilterNameEditFieldLabel     matlab.ui.control.Label
        FilterNameEditField          matlab.ui.control.EditField
        DetectorEditFieldLabel       matlab.ui.control.Label
        DetectorEditField            matlab.ui.control.EditField
        PoweruWEditFieldLabel        matlab.ui.control.Label
        PoweruWEditField             matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel   matlab.ui.control.Label
        WavelengthnmEditField        matlab.ui.control.NumericEditField
        RemainingTimeScansEditFieldLabel  matlab.ui.control.Label
        RemainingTimeScansEditField  matlab.ui.control.NumericEditField
        ZoffsetEditFieldLabel        matlab.ui.control.Label
        ZoffsetEditField             matlab.ui.control.NumericEditField
        Panel5                       matlab.ui.container.Panel
        UpYButton                    matlab.ui.control.StateButton
        DownYButton                  matlab.ui.control.StateButton
        RightXButton                 matlab.ui.control.StateButton
        LeftXButton                  matlab.ui.control.StateButton
        DecZButton                   matlab.ui.control.StateButton
        IncZButton                   matlab.ui.control.StateButton
        MovenmEditFieldLabel         matlab.ui.control.Label
        MovenmEditField              matlab.ui.control.NumericEditField
        MaxZEditFieldLabel           matlab.ui.control.Label
        MaxZEditField                matlab.ui.control.NumericEditField
        MinZEditFieldLabel           matlab.ui.control.Label
        MinZEditField                matlab.ui.control.NumericEditField
        ActPanelButton               matlab.ui.control.Button
        Goto000Button                matlab.ui.control.StateButton
        ZSliderLabel                 matlab.ui.control.Label
        ZSlider                      matlab.ui.control.Slider
        Panel6                       matlab.ui.container.Panel
        XcoordEditFieldLabel         matlab.ui.control.Label
        XcoordEditField              matlab.ui.control.NumericEditField
        YcoordEditFieldLabel         matlab.ui.control.Label
        YcoordEditField              matlab.ui.control.NumericEditField
        ZcoordEditFieldLabel         matlab.ui.control.Label
        ZcoordEditField              matlab.ui.control.NumericEditField
        Panel7                       matlab.ui.container.Panel
        LoadFigureButton             matlab.ui.control.Button
        SetoffsetButton              matlab.ui.control.StateButton
        ZoomButton                   matlab.ui.control.StateButton
        SetFigButton                 matlab.ui.control.StateButton
        PrevFigButton                matlab.ui.control.StateButton
        MinColorbarEditFieldLabel    matlab.ui.control.Label
        MinColorbarEditField         matlab.ui.control.NumericEditField
        MaxColorbarEditFieldLabel    matlab.ui.control.Label
        MaxColorbarEditField         matlab.ui.control.NumericEditField
        SavezoomButton               matlab.ui.control.StateButton
        ZoomSizeumEditFieldLabel     matlab.ui.control.Label
        ZoomSizeumEditField          matlab.ui.control.NumericEditField
        CleanFigButton               matlab.ui.control.StateButton
        Panel3                       matlab.ui.container.Panel
        TrackerSwitchLabel           matlab.ui.control.Label
        TrackerSwitch                matlab.ui.control.Switch
        StartOptButton               matlab.ui.control.StateButton
        XYRangeEditFieldLabel        matlab.ui.control.Label
        XYRangeEditField             matlab.ui.control.NumericEditField
        ZRangeEditFieldLabel         matlab.ui.control.Label
        ZRangeEditField              matlab.ui.control.NumericEditField
    end

    
properties (Access = private)
        DAQSession = [] % Handle to DAQ session
        
        DAQClkTraceSession = [] % Handle to DAQ session
        
        
        DAQTrazaScan = [] % sesion de traza a ejecutar durante scan
        trazaScanListener = [];
        traceScanFigure % Handle a figura externa que plotea la traza
        axesScanTrace % Handle de los ejes de figura que plotea traza
        lineScanTrace % Handle a la linea del gráfico
        DataFIFOBufferTrazaScan
        TimestampsFIFOBufferTrazaScan
        
        TraceAvailableListener % Listener for DAQ session 'DataAvailable' event
        
        
        TimestampsFIFOBuffer % Timestamps FIFO buffer used for live plot of latest "N" seconds of acquired data
        
        DataFIFOBuffer  % Data FIFO buffer used for live plot of latest "N" seconds of acquired data
        
        traceFigure % Handle a figura externa que plotea la traza
        axesTrace % Handle de los ejes de figura que plotea traza
        lineTrace % Handle a la linea del gráfico
        
        % VARIABLES PARA SINCRONIZAR OPTIMIZACION
        acumularCtas = false; % Bandera lógica para dar paso a optimizacion
        ctasAcumuladasOpt = []; % variable para acumular cuentas
        
        trackerReady = false; % Verifica que este calculado el promedio para trackear
        
        %========================================
        
        LogRequested % Logical value, indicates whether user selected to log data to file from the UI (set by LogdatatofileSwitch)
        LogSaveScan % Valor booleano para guardar datos tomados del scan
        TriggerTime % Acquisition start time stored as datetime
        TempFilename % Temporary binary file name, acquired data is logged to this file during acquisition
        TempFile % Handle of opened binary file, acquired data is logged to this file during acquisition
        Filename = datestr(datetime('now'), 'yyyy-mm-dd_HHMMSS_TRA') % Default MAT file name at app start
        Filepath = pwd % Default folder for saving the MAT file at app start
        
        % Propiedades asociadas a mapa PL
        
        
        DAQScanSession = [] % Sesion para realizar scan
        scanListener = [];
        
        % Sesiones para manejo de coordenadas por separado
        
        DAQSesionCoordX = [] % Sesion para manejar entrada y salidas de voltajes asociadas a coordenada X
        
        DAQSesionCoordY = [] % Sesion para manejar entrada y salidas de voltajes asociadas a coordenada Y
        
        DAQSesionCoordZ = [] % Sesion para manejar entrada y salidas de voltajes asociadas a coordenada Z
        
        CountdownTimer % Timer pare cuenta regresiva de scan
        
        transCoords = false % Bandera para trackear coordenadas actuales a scan
        piezoSettlingTime = 2E-3 % Tiempo que le toma al piezo moverse de un punto a otro
        
        xCoordCts % X coordinates scanned
        yCoordCts % Y coordinates scanned
        xCoordsZoom % Coordenadas X recorridas en zoom
        yCoordsZoom % Coordenadas Y recorridas en zoom
        xOffSetZoom % Centro en X del zoom
        yOffSetZoom % Centro en Y del zoom
        zOffSetZoom % Centro en Z del zoom
        sizeZoom % Tamaño del scan en el zoom
        countsMatrix = [] % Matrix associated with counts/s at the given coordinates
        countsZoom = []
        matrixPlot 
        isClickedDown = false
        buttonDownTime % Variable para medir el tiempo que se mantiene presionado el clic
        
        currentVoltX  % Voltaje asociado a posicion actual X
        currentVoltY  % Voltaje asociado a posicion actual Y
        currentVoltZ % Voltaje asociado a posicion actual Z
        maxVoltPiezo = 0.25 % Voltaje máximo a aplicar al piezo
        
        hXLine  % Handle para la línea vertical
        hYLine  % Handle para la línea horizontal
        
        minColorbar
        maxColorbar
        
        voltX = 0
        voltY = 0
        voltZ = 0
    end


    methods (Access = private)
        
        function dataAvailable_Callback(app, ~, event)
        %dataAvailable_Callback Executes on DAQ session DataAvailable event
        %  This callback function gets executed periodically as more data is acquired.
        %  For a smooth live plot update, it stores the latest N seconds
        %  (specified time window) of acquired data and relative timestamps in FIFO
        %  buffers. A live plot is updated with the data in the FIFO buffer.
        %  If data logging option is selected in the UI, it also writes data to a
        %  binary file.
        
        
%         if app.acumularCtas
%             app.ctasAcumuladasOpt = [app.ctasAcumuladasOpt; event.Data(:,1)];
%         end
        
            if app.LogRequested
                % If Log data to file switch is on
                data = [event.TimeStamps, event.Data]' ;
                fwrite(app.TempFile, data, 'double');
                if event.TimeStamps(1)==0
                    app.TriggerTime = event.TriggerTime;
                end
            end
            
            % Store continuous acquisition data in FIFO data buffers
            buffersize = round(abs(app.NBufferEditField.Value));
            
            % Definir variables que determinan tiempo de integración
            timeMs = app.IntTimemsEditField.Value;
            dT = timeMs/(1000*2);
            
            data = mean(diff(event.Data(:,1)))/(2*dT);
            
            if app.acumularCtas
                app.ctasAcumuladasOpt = [app.ctasAcumuladasOpt; data];
            end
            
            
            app.DataFIFOBuffer = storeDataInFIFO(app, app.DataFIFOBuffer, buffersize, data);
            
            if numel(app.DataFIFOBuffer) == buffersize
                app.MeanEditField.Value = mean(app.DataFIFOBuffer(1:end));
                app.STDEditField.Value = std(app.DataFIFOBuffer(1:end));
                
            end
            
            if (app.trackerReady && (numel(app.DataFIFOBuffer) == buffersize) && (app.MeanEditField.Value < app.MeanEditField.Value*0.7))
                
                StartOptButtonValueChanged(app, event);
                app.DataFIFOBuffer = [app.DataFIFOBuffer(end)];
                
            end
            
            % Update plot data
            set(app.lineTrace ,'YData', app.DataFIFOBuffer);
            drawnow limitrate;
            
        end
        
        function trazaScanCallback(app, ~, event)
        
            % Store continuous acquisition data in FIFO data buffers
            buffersize = round(abs(app.NBufferEditField.Value));
            
            % Definir variables que determinan tiempo de integración
            timeMs = 2;
            dT = timeMs/(1000*2);
            
            data = mean(diff(event.Data(:,1)))/(2*dT);
            
            
            app.DataFIFOBufferTrazaScan = storeDataInFIFO(app, app.DataFIFOBufferTrazaScan, buffersize, data);
            
            
            % Update plot data
            set(app.lineScanTrace ,'YData', app.DataFIFOBufferTrazaScan);
            drawnow limitrate;
            
        end
        
        % Callback que se ejecuta al cerrar la figura externa
        function closeTrazaCallback(app, src, event)
            % Cerrar la sesión de DAQ
            StopTraceButtonValueChanged(app,[])
            % Cerrar la figura
            %delete(src);
        end
        
        function data = storeDataInFIFO(~, data, buffersize, datablock)
        %storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
        %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
        %  a smooth live plot update and without continuously increasing memory use.
        %  The most recently acquired data (DATABLOCK) is added to the buffer and if the amount of data in the 
        %  buffer exceeds the specified buffer size (BUFFERSIZE) the oldest data is discarded to cap the size of
        %  the data in the buffer to BUFFERSIZE.
        %  input DATA is the existing data buffer (column vector Nx1).
        %  BUFFERSIZE is the desired buffer size (maximum number of rows in DATA buffer) and can be changed.
        %  DATABLOCK is a new data block to be added to the buffer (column vector Kx1).
        %  output DATA is the updated data buffer (column vector Mx1).
        
        % If the data size is greater than the buffer size, keep only the
        % the latest "buffer size" worth of data
        % This can occur if the buffer size is changed to a lower value during acquisition
            if size(data,1) > buffersize
                data = data(end-buffersize+1:end,:);
            end
            
            if size(datablock,1) < buffersize
                % Data block size (number of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = size(datablock,1);
                    data = circshift(data,-shiftPosition);
                    data(end-shiftPosition+1:end,:) = datablock;
                elseif (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data; datablock];
                    shiftPosition = size(data,1) - buffersize;
                    data = circshift(data,-shiftPosition);
                    data(buffersize+1:end, :) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end
            
        end
        
        function setAppViewState(app, state)
        %setAppViewState Sets the app in a new state and enables/disables corresponding components
        %  STATE can be 'deviceselection', 'configuration', or 'acquisition'
        
            switch state              
                case 'configuration'
                    
                    app.ScanButton.Enable = 'on';
                    app.StopscanButton.Enable = 'off';
                    app.LiveViewPanel.Visible = 'on';
                    
                    if ~(isempty(app.DAQSession)) && app.DAQSession.IsRunning
                        app.StartTraceButton.Enable = 'off';
                        app.StopTraceButton.Enable = 'on';
                        app.SavingDataSwitch.Enable = 'off';
                        app.NotifyScansSpinner.Enable = 'off';
                        app.IntTimemsEditField.Enable = 'off';
                        app.NBufferEditField.Enable = 'off';
                        
                    else
                        app.StartTraceButton.Enable = 'on';
                        app.StopTraceButton.Enable = 'off';
                        app.SavingDataSwitch.Enable = 'on';
                        app.NotifyScansSpinner.Enable = 'on';
                        app.IntTimemsEditField.Enable = 'on';
                        app.NBufferEditField.Enable = 'on';
                    end
                    
                    
                    app.XoffsetEditField.Enable = 'on';
                    app.YoffsetEditField.Enable = 'on';
                    app.ZoffsetEditField.Enable = 'on';
                    app.ScanFreqEditField.Enable = 'on';
                    app.ScanSizeumEditField.Enable = 'on';
                    app.ScanNpixelsEditField.Enable = 'on';
                    app.StimemsEditField.Enable = 'on';
                    app.CtimemsEditField.Enable = 'on';
                    
                    app.LoadFigureButton.Enable = 'on';
                    app.Goto000Button.Enable = 'on';
                    app.SetoffsetButton.Enable = 'on';
                    app.UpYButton.Enable = 'on';
                    app.DownYButton.Enable = 'on';
                    app.LeftXButton.Enable = 'on';
                    app.RightXButton.Enable = 'on';
                    app.MinZEditField.Enable = 'on';
                    app.MaxZEditField.Enable = 'on';
                    app.ZSlider.Enable = 'on';
                    app.DecZButton.Enable = 'on';
                    app.IncZButton.Enable = 'on';
                    app.MovenmEditField.Enable = 'on';
                    app.XcoordEditField.Enable = 'on';
                    app.YcoordEditField.Enable = 'on';
                    app.ZcoordEditField.Enable = 'on';
                    
                case 'acquisition'
                    app.SavingDataSwitch.Enable = 'off';
                    app.NotifyScansSpinner.Enable = 'off';
                    app.IntTimemsEditField.Enable = 'off';
                    app.NBufferEditField.Enable = 'off';
                    app.StartTraceButton.Enable = 'off';
                    app.StopTraceButton.Enable = 'on';
                    
                case 'scanning'
                    app.SavingDataSwitch.Enable = 'on';
                    app.NotifyScansSpinner.Enable = 'on';
                    app.IntTimemsEditField.Enable = 'on';
                    app.NBufferEditField.Enable = 'on';
                    app.StartTraceButton.Enable = 'off';
                    app.ScanButton.Enable = 'off';
                    app.StopTraceButton.Enable = 'off';
                    app.StopscanButton.Enable = 'on';
                    app.SaveScanButton.Enable = 'off';
                    app.ActPanelButton.Enable = 'off';
                    
                    app.XoffsetEditField.Enable = 'off';
                    app.YoffsetEditField.Enable = 'off';
                    app.ZoffsetEditField.Enable = 'off';
                    app.ScanFreqEditField.Enable = 'off';
                    app.ScanSizeumEditField.Enable = 'off';
                    app.ScanNpixelsEditField.Enable = 'off';
                    app.StimemsEditField.Enable = 'off';
                    app.CtimemsEditField.Enable = 'off';
                    
                    app.LoadFigureButton.Enable = 'off';
                    app.Goto000Button.Enable = 'off';
                    app.SetoffsetButton.Enable = 'off';
                    app.UpYButton.Enable = 'off';
                    app.DownYButton.Enable = 'off';
                    app.LeftXButton.Enable = 'off';
                    app.RightXButton.Enable = 'off';
                    app.MinZEditField.Enable = 'off';
                    app.MaxZEditField.Enable = 'off';
                    app.ZSlider.Enable = 'off';
                    app.DecZButton.Enable = 'off';
                    app.IncZButton.Enable = 'off';
                    app.MovenmEditField.Enable = 'off';
                    app.XcoordEditField.Enable = 'off';
                    app.YcoordEditField.Enable = 'off';
                    app.ZcoordEditField.Enable = 'off';
                    
                    app.LiveViewPanel.Visible = 'off';
                    
                case 'scannerFinished'
                    app.SavingDataSwitch.Enable = 'on';
                    app.NotifyScansSpinner.Enable = 'on';
                    app.IntTimemsEditField.Enable = 'on';
                    app.NBufferEditField.Enable = 'on';
                    app.StartTraceButton.Enable = 'off';
                    app.ScanButton.Enable = 'on';
                    app.StopTraceButton.Enable = 'off';
                    app.StopscanButton.Enable = 'off';
                    app.ActPanelButton.Enable = 'on';
                    app.SaveScanButton.Enable = 'on';
                    
                    app.XoffsetEditField.Enable = 'on';
                    app.YoffsetEditField.Enable = 'on';
                    app.ScanFreqEditField.Enable = 'on';
                    app.ScanSizeumEditField.Enable = 'on';
                    app.ScanNpixelsEditField.Enable = 'on';
                    app.StimemsEditField.Enable = 'on';
                    app.CtimemsEditField.Enable = 'on';
                    
                    app.LoadFigureButton.Enable = 'off';
                    app.Goto000Button.Enable = 'off';
                    app.SetoffsetButton.Enable = 'off';
                    app.UpYButton.Enable = 'off';
                    app.DownYButton.Enable = 'off';
                    app.LeftXButton.Enable = 'off';
                    app.RightXButton.Enable = 'off';
                    app.MinZEditField.Enable = 'off';
                    app.MaxZEditField.Enable = 'off';
                    app.ZSlider.Enable = 'off';
                    app.DecZButton.Enable = 'off';
                    app.IncZButton.Enable = 'off';
                    app.MovenmEditField.Enable = 'off';
                    app.XcoordEditField.Enable = 'off';
                    app.YcoordEditField.Enable = 'off';
                    app.ZcoordEditField.Enable = 'off';
                    
                    app.LiveViewPanel.Visible = 'off';
                    
                case 'scannerStopped'
                    app.SavingDataSwitch.Enable = 'on';
                    app.NotifyScansSpinner.Enable = 'on';
                    app.IntTimemsEditField.Enable = 'on';
                    app.NBufferEditField.Enable = 'on';
                    app.StartTraceButton.Enable = 'off';
                    app.ScanButton.Enable = 'on';
                    app.StopTraceButton.Enable = 'off';
                    app.StopscanButton.Enable = 'off';
                    app.ActPanelButton.Enable = 'off';
                    app.SaveScanButton.Enable = 'off';
                    
                    app.XoffsetEditField.Enable = 'on';
                    app.YoffsetEditField.Enable = 'on';
                    app.ScanFreqEditField.Enable = 'on';
                    app.ScanSizeumEditField.Enable = 'on';
                    app.ScanNpixelsEditField.Enable = 'on';
                    app.StimemsEditField.Enable = 'on';
                    app.CtimemsEditField.Enable = 'on';
                    
                    app.LoadFigureButton.Enable = 'off';
                    app.Goto000Button.Enable = 'off';
                    app.SetoffsetButton.Enable = 'off';
                    app.UpYButton.Enable = 'off';
                    app.DownYButton.Enable = 'off';
                    app.LeftXButton.Enable = 'off';
                    app.RightXButton.Enable = 'off';
                    app.MinZEditField.Enable = 'off';
                    app.MaxZEditField.Enable = 'off';
                    app.ZSlider.Enable = 'off';
                    app.DecZButton.Enable = 'off';
                    app.IncZButton.Enable = 'off';
                    app.MovenmEditField.Enable = 'off';
                    app.XcoordEditField.Enable = 'off';
                    app.YcoordEditField.Enable = 'off';
                    app.ZcoordEditField.Enable = 'off';
                    
                    app.LiveViewPanel.Visible = 'off';
                    
                    
            end
        end
        
        function binFile2MAT(~, filenameIn, filenameOut, numColumns, metadata)
        %BINFILE2MAT Loads 2-D array of doubles from binary file and saves data to MAT file
        % Processes all data in binary file (filenameIn) and saves it to a MAT file without loading
        % all data to memory.
        % If output MAT file (filenameOut) already exists, data is overwritten (not appended).
        % Input binary file is a matrix of doubles with numRows x numColumns
        % MAT file (filenameOut) is a MAT file with the following variables
        %   timestamps = a column vector ,  the first column in the data from binary file
        %   data = a 2-D array of doubles, includes 2nd-last columns in the data from binary file
        %   metatada = a structure, which is provided as input argument, used to provide additional
        %              data information
        %
            
            % If filenameIn does not exist, error out
            if ~exist(filenameIn, 'file')
                error('Input binary file ''%s'' not found. Specify a different file name.', filenameIn);
            end
            
            % If output MAT file already exists, delete it
            if exist(filenameOut, 'file')
                delete(filenameOut)
            end
            
            % Determine number of rows in the binary file
            % Expecting the number of bytes in the file to be 8*numRows*numColumns
            fileInfo = dir(filenameIn);
            numRows = floor(fileInfo.bytes/(8*double(numColumns)));
            
            % Create matfile object to save data loaded from binary file
            matObj = matfile(filenameOut);
            matObj.Properties.Writable = true;
            
            % Initialize MAT file
            matObj.timestamps(numRows,1) = 0;
            matObj.data(numRows,1) = 0;
            
            % Open input binary file
            fid = fopen(filenameIn,'r');
            
            % Specify how many rows to process(load and save) at a time
            numRowsPerChunk = 10E+6;
            
            % Keeps track of how many rows have been processed so far
            ii = 0;
            
            while(ii < numRows)
                
                % chunkSize = how many rows to process in this iteration
                % If it's the last iteration, it's possible the number of rows left to
                % process is different from the specified numRowsPerChunk
                chunkSize = min(numRowsPerChunk, numRows-ii);
                
                data = fread(fid, [numColumns,chunkSize], 'double');
                
                matObj.timestamps((ii+1):(ii+chunkSize), 1) = data(1,:)';
                matObj.data((ii+1):(ii+chunkSize), 1) = data(2:end,:)';
                
                ii = ii + chunkSize;
            end
            
            fclose(fid);
            
            % Save provided metadata to MAT file
            matObj.metadata = metadata;
        end
        
        % Función para guardar scan y zoom
        
        function guardarRespaldoScan(app)
            
            %DATOS INDEPENDIENTES DE LA TAREA
            metadataScanStruct.nPixels = app.ScanNpixelsEditField.Value;
            metadataScanStruct.frecuencia = app.ScanFreqEditField.Value;
            metadataScanStruct.settlingTime = app.StimemsEditField.Value;
            metadataScanStruct.countsTime = app.CtimemsEditField.Value;
            
            % Metadata correspondiente a la muestra
            metadataMuestraStruct.muestra = app.SampleNameEditField.Value;
            metadataMuestraStruct.filtro = app.FilterNameEditField.Value;
            metadataMuestraStruct.detector = app.DetectorEditField.Value;
            metadataMuestraStruct.potencia = app.PoweruWEditField.Value;
            metadataMuestraStruct.longOnda = app.WavelengthnmEditField.Value;
            
            % Obtener la fecha y hora actual
            timestamp = datetime('now', 'Format', 'yyyy-MM-dd');
            timestampStr = datestr(timestamp, 'yyyy-mm-dd');  % Fecha solo con día, mes y año
            
            
            % Obtener la carpeta actual
            currentFolder = pwd;
            
            % Definir nombre de archivo de respaldo (con solo día, mes y año)
            backupFilename = ['respaldo_', timestampStr, '.mat'];
            backupFullPath = fullfile(currentFolder, backupFilename);
            
            %DATOS DEPENDIENTES DE LA TAREA
            % Definir los datos a guardar
            dataStruct.c = app.countsMatrix;
            dataStruct.x = app.xCoordCts;
            dataStruct.y = app.yCoordCts;
            
            % Metadata correspondiente al scan
            metadataScanStruct.posX = app.XoffsetEditField.Value;
            metadataScanStruct.posY = app.YoffsetEditField.Value;
            metadataScanStruct.posZ = app.ZoffsetEditField.Value;
            metadataScanStruct.scanSize = app.ScanSizeumEditField.Value;
            
            % Guardar datos y metadata en una estructura
            scan.data = dataStruct;
            scan.metadataMuestra = metadataMuestraStruct;
            scan.metadataScan = metadataScanStruct;
            
            % Guardar archivo de respaldo (sin hora)
            save(backupFullPath, 'scan');
            disp(['Respaldo guardado en: ', backupFullPath]);
            
        end


        function guardarDatosScan(app, tipoScan)
            
            
            %DATOS INDEPENDIENTES DE LA TAREA


            % Metadata correspondiente a la muestra
            metadataMuestraStruct.muestra = app.SampleNameEditField.Value;
            metadataMuestraStruct.filtro = app.FilterNameEditField.Value;
            metadataMuestraStruct.detector = app.DetectorEditField.Value;
            metadataMuestraStruct.potencia = app.PoweruWEditField.Value;
            metadataMuestraStruct.longOnda = app.WavelengthnmEditField.Value;


            % Obtener la fecha y hora actual
            timestamp = datetime('now', 'Format', 'yyyy-MM-dd_HHmmss');
            timestampStr = datestr(timestamp, 'yyyy-mm-dd_HHMMSS');
            
            % Generar el nombre del archivo
            usrName = app.SampleNameEditField.Value;
            
            % Obtener la carpeta actual
            currentFolder = pwd;


            %DATOS DEPENDIENTES DE LA TAREA
            
            switch tipoScan
                case 'scan'
                    % Definir los datos a guardar
                    dataStruct.c = app.countsMatrix;
                    dataStruct.x = app.xCoordCts;
                    dataStruct.y = app.yCoordCts;
                    
                    % Metadata correspondiente al scan
                    
                    metadataScanStruct.nPixels = app.ScanNpixelsEditField.Value;
                    metadataScanStruct.frecuencia = app.ScanFreqEditField.Value;
                    metadataScanStruct.settlingTime = app.StimemsEditField.Value;
                    metadataScanStruct.countsTime = app.CtimemsEditField.Value;
                    
                    metadataScanStruct.posX = app.XoffsetEditField.Value;
                    metadataScanStruct.posY = app.YoffsetEditField.Value;
                    metadataScanStruct.posZ = app.ZoffsetEditField.Value;
                    metadataScanStruct.scanSize = app.ScanSizeumEditField.Value;


                    % Guardar datos y metadata en una estructura
                    scan.data = dataStruct;
                    scan.metadataMuestra = metadataMuestraStruct;
                    scan.metadataScan = metadataScanStruct;
                    
                    filename = [timestampStr, '_', usrName, '_SCAN.mat'];
                    
                    % Generar la ruta completa del archivo
                    fullPath = fullfile(currentFolder, filename);
                    
                    % Guardar los datos en un archivo .mat
                    save(fullPath, 'scan');
                    
                    % Mostrar un mensaje de confirmación
                    disp(['Scan guardado en: ', fullPath]);
                    
                case 'zoom'
                    % Definir los datos a guardar
                    dataStruct.c = app.countsZoom;
                    dataStruct.x = app.xCoordsZoom;
                    dataStruct.y = app.yCoordsZoom;
                    
                    % Metadata correspondiente al scan
                    
                    metadataZoomStruct.nPixels = app.ScanNpixelsEditField.Value;
                    metadataZoomStruct.frecuencia = app.ScanFreqEditField.Value;
                    metadataZoomStruct.settlingTime = app.StimemsEditField.Value;
                    metadataZoomStruct.countsTime = app.CtimemsEditField.Value;
                    
                    metadataZoomStruct.posX = app.xOffSetZoom;
                    metadataZoomStruct.posY = app.yOffSetZoom;
                    metadataZoomStruct.posZ = app.zOffSetZoom;
                    metadataZoomStruct.scanSize = app.sizeZoom;
                    
                    % Guardar datos y metadata en una estructura
                    zoom.data = dataStruct;
                    zoom.metadataMuestra = metadataMuestraStruct;
                    zoom.metadataScan = metadataZoomStruct;
                    
                    filename = [timestampStr, '_', usrName, '_ZOOM.mat'];
                    
                    % Generar la ruta completa del archivo
                    fullPath = fullfile(currentFolder, filename);
                    
                    % Guardar los datos en un archivo .mat
                    save(fullPath, 'zoom');
                    
                    % Mostrar un mensaje de confirmación
                    disp(['Zoom guardado en: ', fullPath]);
            end


        end
        
        
        function closeApp_Callback(app, ~, event, isAcquiring)
        %closeApp_Callback Executes after "Close Confirm" dialog window
        %  "Close Confirm" dialog window is launched from LiveDataAcquisitionCloseRequest.
        %   before closing app if acquisition is currently on (isAcquiring=true) clean-up 
        %   DAQ session and close file if logging.
        
            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQSession)
                        stop(app.DAQClkTraceSession)
                        stop(app.DAQScanSession)
                        delete(app.TraceAvailableListener)
                        delete(app.DAQSession)
                        delete(app.DAQClkTraceSession)
                        delete(app.DAQScanSession)

                        
                        if app.LogRequested
                            fclose(app.TempFile);
                        end
                    else
                        % Acquisition is stopped
                    end

                    delete(app)
                case 'Cancel'
                    % Continue
            end
            
        end
        
        function updateLogdatatofileSwitchComponents(app)
            value = app.SavingDataSwitch.Value;
            switch value
                case 'Off'
                    app.LogRequested = false;
                case 'On'
                    app.LogRequested = true;
            end
        end
        
        function actualizarSliderZ(app, newValue)
            
            limSlider = app.ZSlider.Limits;
            
            % Ajustar valroes en slider
            app.ZSlider.Limits = [min(limSlider(1), newValue), max(limSlider(2), newValue)];
            
            app.ZSlider.Value = newValue;
        end
        
        % Función que lee y actualiza voltajes del piezo
        
        function updatePiezoVoltage(app)
            
            % Leer voltajes
            
            voltajes = inputSingleScan(app.DAQScanSession);
            % Asignar voltajes leidos a variables de clase
            
            app.currentVoltX = recortarVoltaje(app, voltajes(2), 0.0, 10.0);
            app.currentVoltY = recortarVoltaje(app, voltajes(3), 0.0, 10.0);


            % Actualizar valores actuales de voltaje en UI
            app.XcoordEditField.Value = app.currentVoltX * 8;
            app.YcoordEditField.Value = app.currentVoltY * 8;
            app.ZcoordEditField.Value = app.currentVoltZ * 8;
            
            actualizarSliderZ(app, app.ZcoordEditField.Value)
            
        end
        
        % FUNCIONES ASOCIADAS A MAPA PL
        
        % Callback para greaficar matriz y setear listeners de interacción
        % con imagen
        
        function dataLoadedCallback(app)
            % Usar imagesc para graficar amtriz y setear límites
            app.matrixPlot = imagesc(app.UIAxes,app.xCoordCts, app.yCoordCts, app.countsMatrix);
            xlim(app.UIAxes, [min(app.xCoordCts), max(app.xCoordCts)]);
            ylim(app.UIAxes, [min(app.yCoordCts), max(app.yCoordCts)]);
            colorbar(app.UIAxes)
            
            % Inicializar líneas de coordenadas
            posX = app.currentVoltX * 8;
            posY = app.currentVoltY * 8;
            hold(app.UIAxes, 'on');
            app.hXLine = xline(app.UIAxes, posX, 'k');
            app.hYLine = yline(app.UIAxes, posY, 'k');
            hold(app.UIAxes, 'off');
            
            % Obtener la barra de herramientas del axes
            tb = axtoolbar(app.UIAxes);
            
            % Eliminar herramientas específicas
            delete(tb.Children);
            
            
            % Crear lsiteners de interaccion con figura para establecer
            % coordenadas
            set(app.matrixPlot, 'ButtonDownFcn', @(src, event)clickDown(app, src, event));
            % Configurar las funciones de liberación del click
            set(app.UIFigure, 'WindowButtonUpFcn', @(src, event)clickUp(app, src, event));
        end
        
       function clickDown(app, src, event)


            % Obtener la posición del click down
            app.buttonDownTime = tic;
            pos = get(app.UIAxes, 'CurrentPoint');
            x = round(pos(1,1),4);
            y = round(pos(1,2),4);
            app.isClickedDown = true;
            actualizarVoltajes(app)
            
            
            % Verificar que el click se haya hecho dentro de los límites de la figura
            
            if x >= min(app.xCoordCts) && x <= max(app.xCoordCts) && y >= min(app.yCoordCts) && y <= max(app.yCoordCts)
                
                
                % Actualizar las líneas de coordenadas
                
                set(app.hXLine, 'Value', x);
                set(app.hYLine, 'Value', y);
                
                % Actualizar la posición clickeada como voltaje final
                
                app.voltX = x/8;
                app.voltY = y/8;
                
                
                % Desplazar piezo desde posición anterior hasta donde se
                % hace click
                
                desplazarCoordenada(app, 'x')
                desplazarCoordenada(app, 'y')
                
                actualizarVoltajes(app)
                
            end
            pause(0.01)
            % Configurar las funciones de movimiento y liberación del click
            set(app.UIFigure, 'WindowButtonMotionFcn', @(src, event)whileClickDown(app, src, event));
        end
        
        function whileClickDown(app, src, event)
            
            % Obtener posiciones mientras se mantiene presionado el click
            
             if(app.isClickedDown==true)
                
                % Posición dad por el mouse
                
                pos = get(app.UIAxes, 'CurrentPoint');
                x = round(pos(1,1),4);
                y = round(pos(1,2),4);
                
                % Verificar que el mouse esté dentro de los límites de la figura
            
                if x >= min(app.xCoordCts) && x <= max(app.xCoordCts) && y >= min(app.yCoordCts) && y <= max(app.yCoordCts)
                    
                    % Mostrar en UI posición del mouse
                    
                    app.XcoordEditField.Value = x;
                    app.YcoordEditField.Value = y;
                    
                    % Actualizar la posición del mouse como voltaje final
                    
                    app.voltX = x/8;
                    app.voltY = y/8;
                    
                    % Desplazar piezo por coordenada considerando voltaje
                    % final
                    desplazarCoordenada(app,'x')
                    desplazarCoordenada(app,'y')
                    
                    % Actualizar voltajes del piezo
                    actualizarVoltajes(app);
                    
                end
                
            end
            
        end
        
        function clickUp(app, src, event)
            
            % Dejar de arrastar coordenadas
            app.isClickedDown = false;
            
            % Obtener las posiciones cuando se deja de presionar el click
            
            pos = get(app.UIAxes, 'CurrentPoint');
            x = round(pos(1,1),4);
            y = round(pos(1,2),4);


            % Verificar que el click se haya levantado dentro de los límites de la figura
            % y el tiempo que estuvo presionado
            
            if x >= min(app.xCoordCts) && x <= max(app.xCoordCts) && y >= min(app.yCoordCts) && y <= max(app.yCoordCts)
                
                % Actualizar las líneas de coordenadas en la figura
                
                set(app.hXLine, 'Value', x);
                set(app.hYLine, 'Value', y);
                
                % Mostrar en UI posición del mouse al levantr click
%                 actualizarVoltajes(app)
                
            end
            
        end


        % Iguala el largo de los vectores de voltaje de salida
        
        function [outVectorX, outVectorY] = equalizeVectorLength(app, vectorVoltX, vectorVoltY)
            
            % Obtener tamaño de los vectores
            
            sizeVoltX = length(vectorVoltX);
            sizeVoltY = length(vectorVoltY);
            
            % Crear vector con los tamaños calculados
            
            largoVoltajes = [sizeVoltX, sizeVoltY];
            
            % Encontrar el tamaño del vector mas largo de voltajes
            
            maxLength = max(largoVoltajes);
            
            % Calcular cantidad de veces a repetir ultimo elemento para
            % cada salida de voltaje
            
            numRepVoltX = maxLength - sizeVoltX;
            numRepVoltY = maxLength - sizeVoltY;
            
            % Homogeneizar el largo de las salidas de voltaje
            
            if numRepVoltX > 0
                outVectorX = [vectorVoltX, repelem(vectorVoltX(end), numRepVoltX)];
            else
                outVectorX = vectorVoltX;
            end
            
            if numRepVoltY > 0
                outVectorY = [vectorVoltY, repelem(vectorVoltY(end), numRepVoltY)];
            else
                outVectorY = vectorVoltY;
            end
            
        end
        
        
        % Desplaza el piezo de un punto a otro
        
        function desplazarScan(app)
            
            
            s = app.DAQScanSession;
            
            
            if ~(isempty(s))
                % Crear vector con voltajes iniciales actuales y voltajes
                % finales
                
                voltInicial = [app.currentVoltX, app.currentVoltY];
                
                voltFinal = [app.voltX, app.voltY];
                
                % Calcular distancia en cada coordenada
                
                distPorCoord = abs(voltFinal - voltInicial);
                
                % Verificar si hay saltos de voltaje por sobre umbral
                
                idxUmbral = find(distPorCoord > app.maxVoltPiezo);
                
                if( isempty(idxUmbral) )
                    
                    % Si todos los saltos de voltaje estan por debajo del
                    % umbral se hace output con voltajes finales
                    
                    outputSingleScan(s, voltFinal);
                    
                else
                    
                    % Si existen saltos de voltaje por sobre el umbral
                    % crearemos cola de voltaje del largo asociado al máximo
                    % salto
                    
                    % Calcular el vector con los voltajes de cada coordenada
                    
                    vectorVoltX = voltVector(app, app.currentVoltX, app.voltX);
                    vectorVoltY = voltVector(app, app.currentVoltY, app.voltY);
                    
                    % Igualar el largo de los vectores de voltajes de salida
                    
                    [vectorVoltX, vectorVoltY] = equalizeVectorLength(app, vectorVoltX, vectorVoltY);
                    
                    % Darle salida a los voltajes
                    for i = 1 : length(vectorVoltX)
                        outputSingleScan(s, [vectorVoltX(i), vectorVoltY(i)]);
                    end
                    
                    
                end
                
            else
                iniciarSesionScan(app)
                desplazarScan(app)
            end
            
        end
        
        function scanProcessData(app, event, idxNoCts, idxCts, tarea)
            
            try
                
%                 ctasBruto = [event.TimeStamps event.Data];
%                 
%                 assignin('base','ctasBruto',ctasBruto)
%                 assignin('base','idxNoCts',idxNoCts)
%                 assignin('base','idxCts',idxCts)
                
                preData = event.Data;
                
                for i=1:length(idxNoCts)
                    preData(idxNoCts(i,1):idxNoCts(i,2)) = [];
                end
                
                data = diff(preData);
                
                nPuntos = app.ScanNpixelsEditField.Value;
                integrationTime = (app.CtimemsEditField.Value)/1000;
                bufferCuentas = zeros(nPuntos * nPuntos,1);
                
                for i=1:length(idxCts)
                    bufferCuentas(i) = sum(  data(idxCts(i,1):idxCts(i,2))  )/integrationTime;
                end
                
                switch tarea
                    case 'scan'
                        app.countsMatrix = reshape(bufferCuentas, nPuntos, nPuntos)';
                        app.matrixPlot = imagesc(app.UIAxes,app.xCoordCts, app.yCoordCts, app.countsMatrix);
                        colorbar(app.UIAxes)
                        xlim(app.UIAxes, [min(app.xCoordCts), max(app.xCoordCts)]);
                        ylim(app.UIAxes, [min(app.yCoordCts), max(app.yCoordCts)]);
                        
                        actualizarBarraColor(app, 'scan')
                        
                        guardarRespaldoScan(app)
                    case 'zoom'
                        app.countsZoom = reshape(bufferCuentas, nPuntos, nPuntos)';
                        app.matrixPlot = imagesc(app.UIAxes,app.xCoordsZoom, app.yCoordsZoom, app.countsZoom);
                        
                        xlim(app.UIAxes, [min(app.xCoordsZoom), max(app.xCoordsZoom)]);
                        ylim(app.UIAxes, [min(app.yCoordsZoom), max(app.yCoordsZoom)]);
                        
                        colorbar(app.UIAxes)
                        
                        actualizarBarraColor(app,'zoom')
                        
                end


                % Obtener la barra de herramientas del axes
                tb = axtoolbar(app.UIAxes);
                
                % Eliminar herramientas específicas
                delete(tb.Children);
                figure
                imagesc(app.countsMatrix)
                disp(app.countsMatrix)
                setAppViewState(app, 'scannerFinished')
                
            catch ME
                disp('Error in scanProcessData:');
                disp(ME.message);
            end
            
        end
        
        % Funciones para crear sesiones
        
        function iniciarSesionReloj(app, tarea)
            % Si la sesión ya fue creada cambiamos la frecuencia y se
            % inicia. En caso contrario se crea desde 0.
            
            if ~(isempty(app.DAQClkTraceSession))
                
                % Detener sesion
                stop(app.DAQClkTraceSession)
                
                % Cambiar frecuencia según tarea a realizar
                switch tarea
                    case 'traza'
                        % Obtener tiempo de integración para la traza
                        timeMs = app.IntTimemsEditField.Value;
                        dT = timeMs/(1000*2);
                        app.DAQClkTraceSession.Channels.Frequency = 1/(2*dT);
                    case 'scan'
                        % Obtener frecuencia ingresada por usuario
                        app.DAQClkTraceSession.Channels.Frequency = app.ScanFreqEditField.Value;
                        
                end
                
                % Ejecutar en 2do plano generación de pulsos
                startBackground(app.DAQClkTraceSession)
                
            else
                % Crear sesion para generar pulsos como reloj externo
                s_clk = daq.createSession('ni');
                ch_clk = addCounterOutputChannel(s_clk, 'Dev1', 'ctr1', 'PulseGeneration');


                % Setear delay del pulso, duty cycle y que en estado de reposo esté al
                % máximo
                ch_clk.IdleState = "High";
                ch_clk.InitialDelay = 1E-3;
                ch_clk.DutyCycle = 0.5;
                
                % Frecuencia según tarea a realizar
                switch tarea
                    case 'traza'
                        % Obtener tiempo de integración para la traza
                        timeMs = app.IntTimemsEditField.Value;
                        dT = timeMs/(1000*2);
                        ch_clk.Frequency = 1/(2*dT);
                    case 'scan'
                        % Obtener frecuencia ingresada por usuario
                        ch_clk.Frequency = app.ScanFreqEditField.Value;
                end
                
                
                % Generar pulsos continuamente
                s_clk.IsContinuous = true;
                
                % Guardar sesion como variable de la clase
                app.DAQClkTraceSession = s_clk;
                
                % Ejecutar en 2do plano generación de pulsos
                startBackground(app.DAQClkTraceSession)
            end
            
        end

        
        function iniciarSesionTraza(app)
            
            if ~(isempty(app.DAQScanSession))
                
                % Si hay una sesion asociada al scan estará usando los
                % canales de la traza. 
                
                stop(app.DAQScanSession)
                release(app.DAQScanSession)
                delete(app.DAQScanSession)
                
                % Eliminar listener
                delete(app.TraceAvailableListener);
                app.TraceAvailableListener = [];
                
                app.DAQScanSession = [];
                
                iniciarSesionTraza(app)
                
            else
                %Crear sesion principal para adquisición de cuentas
                s_in = daq.createSession('ni');
                
                % Agregar un canal de entrada para contador
                addCounterInputChannel(s_in, 'Dev1', 'ctr0', 'EdgeCount');
                
                % Configurar reloj para medir traza
                iniciarSesionReloj(app, 'traza')
                
                % Agregar reloj a sesion de traza
                s_in.addClockConnection('External', 'Dev1/PFI13', 'ScanClock');
                
                % Configurar la tasa de muestreo igual a frec. del reloj
                s_in.Rate = app.DAQClkTraceSession.Channels.Frequency;
                
                % Recibir datos de manera continua
                s_in.IsContinuous = true;
                
                % Configurar cantidad de datos para llamar al listener
                s_in.NotifyWhenDataAvailableExceeds = app.NotifyScansSpinner.Value;
                
                % Guardar sesion como variable de la clase
                app.DAQSession = s_in;
                
                % Inicializar listener como arreglo vacío.
                app.TraceAvailableListener = [];
            end
            
        end


        function iniciarSesionScan(app)
            
            if ~(isempty(app.DAQSession))
                % Detener sesion de traza para usar sus canales
                stop(app.DAQSession)
                release(app.DAQSession)
                delete(app.DAQSession)
                
                app.DAQSession = [];
            end
            
            % Verificar si se estan usando los canales del piezo
            
            if ~(isempty(app.DAQSesionCoordX))
                % Detener sesion de coordenada X
                stop(app.DAQSesionCoordX)
                release(app.DAQSesionCoordX)
                delete(app.DAQSesionCoordX)
                
                app.DAQSesionCoordX = [];
            end
            
            if ~(isempty(app.DAQSesionCoordY))
                % Detener sesion de coordenada Y
                stop(app.DAQSesionCoordY)
                release(app.DAQSesionCoordY)
                delete(app.DAQSesionCoordY)
                
                app.DAQSesionCoordY = [];
            end
            
            % Si la sesión del scan está activa solo necesitamos actualizar
            % frecuencia
            if ~(isempty(app.DAQScanSession))
                % Actualizar frecuencia del reloj
                iniciarSesionReloj(app, 'scan')
                
            else
                % Actualizar frecuencia del reloj
                iniciarSesionReloj(app,'scan')
                
                %Crear sesion principal para el scan
                s_scan = daq.createSession('ni');
                
                % Agregar un canal de entrada para contador
                addCounterInputChannel(s_scan, 'Dev1', 'ctr0', 'EdgeCount');
                
                % Configurar reloj
                s_scan.addClockConnection('External', 'Dev1/PFI13', 'ScanClock');
                
                % Configurar la tasa de muestreo igual a frec. del reloj
                s_scan.Rate = app.ScanFreqEditField.Value;
                
                % Recibir datos de manera continua
                s_scan.IsContinuous = true;
                
                % Configuración de salidas de voltaje
                
                % El orden de las coordenadas va como X, Y, Z.
                % Crear salidas analógicas
                addAnalogOutputChannel(s_scan, 'Dev1', 'ao0', 'Voltage');
                addAnalogOutputChannel(s_scan, 'Dev1', 'ao1', 'Voltage');
                
                % Agregar entradas analógicas
                addAnalogInputChannel(s_scan, 'Dev1', 'ai0', 'Voltage');
                addAnalogInputChannel(s_scan, 'Dev1', 'ai4', 'Voltage');
                
                % Guardar sesion como variable de la clase
                app.DAQScanSession = s_scan;
            end
            
            
        end
        
        function iniciarTrazaScan(app)
            if ~(isempty(app.DAQTrazaScan))
                
                % Si hay una sesion asociada al scan estará usando los
                % canales de la traza. 
                
                stop(app.DAQTrazaScan)
                release(app.DAQTrazaScan)
                delete(app.DAQTrazaScan)
                
                % Eliminar listener
                delete(app.trazaScanListener);
                app.trazaScanListener = [];
                
                app.DAQTrazaScan = [];
                
                iniciarTrazaScan(app)
                
            else
                %Crear sesion principal para adquisición de cuentas
                s_in = daq.createSession('ni');
                
                % Agregar un canal de entrada para contador
                addCounterInputChannel(s_in, 'Dev1', 'ctr2', 'EdgeCount');
                
                % Configurar reloj para medir traza
                iniciarSesionReloj(app, 'traza')
                
                % Agregar reloj a sesion de traza
                s_in.addClockConnection('External', 'Dev1/PFI7', 'ScanClock');
                
                % Configurar la tasa de muestreo igual a frec. del reloj
                frecGenerador = 5E2;
                s_in.Rate = frecGenerador;
                
                % Recibir datos de manera continua
                s_in.IsContinuous = true;
                
                % Configurar cantidad de datos para llamar al listener
                s_in.NotifyWhenDataAvailableExceeds = app.NotifyScansSpinner.Value;
                
                % Guardar sesion como variable de la clase
                app.DAQTrazaScan = s_in;
                
                
                % Inicializar listener como arreglo vacío.
                app.trazaScanListener = [];
            end
        end
        
        
        function actualizarCountdown(app)
            
            % diminuir el valor en 1
            currentTime = app.RemainingTimeScansEditField.Value - 1;
            
            % Actualizar el valor en GUI
            app.RemainingTimeScansEditField.Value = currentTime;
            
            % Verificar si la cuenta regresiva ha llegado a 0
            if currentTime <= 0
                stop(app.CountdownTimer);  % Detener el timer
                delete(app.CountdownTimer);  % Eliminar el timer
                app.CountdownTimer = [];
            end
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%% F U N C I O N E S  D E S A C O P L E %%%%%%%%%%%%%%%%%%%%%%%
        
        function iniciarSesionCoordenada(app, valor)
            
            
            switch(valor)
                case 'x'
                    if isempty(app.DAQSesionCoordX)
                        % Crear sesion para entrada y salida de voltajes X
                        s = daq.createSession('ni');
                        addAnalogOutputChannel(s, 'Dev1', 'ao0', 'Voltage');
                        addAnalogInputChannel(s, 'Dev1', 'ai0', 'Voltage');
                        app.DAQSesionCoordX = s;
                    end
                    
                case 'y'
                    if isempty(app.DAQSesionCoordY)
                        % Crear sesion para entrada y salida de voltajes Y
                        s = daq.createSession('ni');
                        addAnalogOutputChannel(s, 'Dev1', 'ao1', 'Voltage');
                        addAnalogInputChannel(s, 'Dev1', 'ai4', 'Voltage');
                        app.DAQSesionCoordY = s;
                    end
                    
                case 'z'
                    if isempty(app.DAQSesionCoordZ)
                        % Crear sesion para entrada y salida de voltajes Y
                        s = daq.createSession('ni');
                        addAnalogOutputChannel(s, 'Dev1', 'ao2', 'Voltage');
                        addAnalogInputChannel(s, 'Dev1', 'ai16', 'Voltage');
                        app.DAQSesionCoordZ = s;
                    end
            end
        end
        
        
        % Entregar voltajes dentro de rango específico
        function voltajeActual = recortarVoltaje(app, voltaje, minVal, maxVal)
            voltajeActual = max(min(voltaje, maxVal), minVal);
        end
        
        
        % Actualizar oltajes considerando sesion de cada cordenada
        function actualizarVoltajes(app)
            % Leer voltaje actual en cada coordenada
            voltajeX = inputSingleScan(app.DAQSesionCoordX);
            voltajeY = inputSingleScan(app.DAQSesionCoordY);
            voltajeZ = inputSingleScan(app.DAQSesionCoordZ);
            
            
            app.currentVoltX = recortarVoltaje(app, voltajeX, 0, 10);
            app.currentVoltY = recortarVoltaje(app, voltajeY, 0, 10);
            app.currentVoltZ = recortarVoltaje(app, voltajeZ, 0, 10);
            
            % Actualizar valores actuales de voltaje en UI
            app.XcoordEditField.Value = app.currentVoltX * 8;
            app.YcoordEditField.Value = app.currentVoltY * 8;
            app.ZcoordEditField.Value = app.currentVoltZ * 8;
            
            actualizarSliderZ(app, app.ZcoordEditField.Value)
        end
        
        
        % Actualizar voltajes desde sesion de Scan
        function actualizarVoltajeScan(app)
            
            % Vector asociado con voltajes de cada entrada
            voltajes = inputSingleScan(app.DAQScanSession);
            
            % Guardar voltajes en variables de app
            app.currentVoltX = recortarVoltaje(app, voltajes(1), 0.0, 10.0);
            app.currentVoltY = recortarVoltaje(app, voltajes(2), 0.0, 10.0);
            
            % Actualizar valores actuales de voltaje y posicion en UI
            app.XcoordEditField.Value = app.currentVoltX * 8;
            app.YcoordEditField.Value = app.currentVoltY * 8;
            
        end
        
        
        % Crear vectores con voltajes de salida con cantidad de elementos
        % que depende de punto inicial y final
        function outVoltage = voltVector(app, voltPtoInicial, voltPtoFinal)
            
            % calcular el voltaje total a desplazar
            
            totalVoltStep = abs(voltPtoFinal-voltPtoInicial);
            
            % Número de steps que tendrá el movimiento
                
            intervalos = ceil(totalVoltStep/app.maxVoltPiezo) + 1; 
                
            % Camino de voltajes a seguir en el piezo durante el
            % desplazamiento
                
            outVoltage = linspace(voltPtoInicial, voltPtoFinal, intervalos);
        end
        
        
        % Desplazar piezo considerando cada coordenada
        function desplazarCoordenada(app, coordenada)
            
            switch coordenada % Determinar canal y voltajes dependiendo de coordenada
                case 'x'
                    outChannel = app.DAQSesionCoordX;
                    voltPtoInicial = app.currentVoltX;
                    voltPtoFinal = app.voltX;
                    app.currentVoltX = app.voltX;
                case 'y'
                    outChannel = app.DAQSesionCoordY;
                    voltPtoInicial = app.currentVoltY;
                    voltPtoFinal = app.voltY;
                    app.currentVoltY = app.voltY;
                case 'z'
                    outChannel = app.DAQSesionCoordZ;
                    voltPtoInicial = app.currentVoltZ;
                    voltPtoFinal = app.voltZ;
                    app.currentVoltZ = app.voltZ;
            end
            
            totalVoltStep = abs(voltPtoFinal-voltPtoInicial); % Voltaje total a desplazar
            
            % Verificar si el desplazamiento total sobrepasa umbral de voltaje
            if(app.maxVoltPiezo < totalVoltStep) 
                
                salidaVoltaje = voltVector(app, voltPtoInicial, voltPtoFinal);
                
                % Iniciar desplazamiento en steps
                for i =1:length(salidaVoltaje)
                    outputSingleScan(outChannel, salidaVoltaje(i));
                    %pause(0.01);
                end
            else
                % Si el desplazamiento no sobrepasa umbral de voltaje
                outputSingleScan(outChannel, voltPtoFinal);
            end
            
        end
        
        
        % Funcion para actualizar valor esperado del timer paa scan
        function tiempoDeEspera(app)
            
            % Frecuencia de escaneo
            frec = app.ScanFreqEditField.Value;
            
            % Cantidad de elementos minimos que debe tener la salida de
            % voltaje en scan
            dimOutput = 0.5 * frec;   
            
            % Tiempo que consideramos que el piezo se mueve
            settlingTime = (app.StimemsEditField.Value)/1000;
            
            % Tiempo durante el cual se considerarán cuentas válidas
            integrationTime = (app.CtimemsEditField.Value)/1000;
            
            % Cantidad de elementos en voltaje constante
            constVoltIntervalo = int64(frec * (settlingTime + integrationTime));
            
            % Cantidad de puntos a escanear
            nPuntos = app.ScanNpixelsEditField.Value;
            
            % Total de elementos en output considerando tramo que devuelve
            % al piezo al (0,0)
            totalElementosOutput = (constVoltIntervalo + 1) * nPuntos * nPuntos;
            
            % Verificar que la salida de voltaje tenga los elementos
            % minimos
            if(totalElementosOutput < dimOutput)
                % Modificar nPuntos dados settlingTime e integrationTime
                nPuntos = ceil(sqrt(0.5 * (settlingTime + integrationTime)^-1));
                totalElementosOutput = (constVoltIntervalo + 1) * nPuntos * nPuntos;
                
                app.ScanNpixelsEditField.Value = nPuntos;
            end
            
            % Configurar tiempo inicial
            initialTime = double( ((settlingTime + integrationTime)*totalElementosOutput)/constVoltIntervalo )  + 8;
            app.RemainingTimeScansEditField.Value = initialTime;
        end
        
        
        function outCoords = vectorCoordsOptimiza(app, coordenada, intervaloOptimizar, stepInicial)
            
            % Crear posiciones a visitar en X e Y
            primerIntervalo = ((-1 * intervaloOptimizar):stepInicial:intervaloOptimizar)';
            segundoIntervalo = ((-0.5 * intervaloOptimizar):(0.5 * stepInicial):(0.5 * intervaloOptimizar))';
            tercerIntervalo = ((-(intervaloOptimizar + stepInicial) * 0.25):(0.25 * stepInicial):((intervaloOptimizar + stepInicial) * 0.25))';
            
            % Intervalo a optimizar se encuentra en nm
            factorConv = 0.001;
            
            % Coordenadas a visitar en um
            outCoords = sort(unique([primerIntervalo; segundoIntervalo; tercerIntervalo])) * factorConv + coordenada;
            
        end
        
        % FUNCIONES PARA OPTIMIZAR
        function [posAjustada, anchuraAjustada] = optimizarPos(app, posiciones, ctasMedidas, anchura)
            
            % Definir la función gaussiana a ajustar
            
            Funcion_fit = @(p) calcularAjusteGaussiano(app, p(1), p(2), p(3), p(4), posiciones);
            
            % Definir la función de error
            err = @(p) sum((Funcion_fit(p) - ctasMedidas).^2);
            
            % Valores iniciales para el ajuste:
            % posición inicial aproximada del pico
            p1 = posiciones(round(mean(find(abs(ctasMedidas - max(ctasMedidas)) < 0.2))));
            
            % anchura inicial proporcionada como parámetro de entrada.
            p2 = anchura;
            
            % vector con los valores iniciales de los parámetros .
            p0 = [p1, p2, (max(ctasMedidas) - min(ctasMedidas)), min(ctasMedidas)];
            
            % Realizar el ajuste utilizando 'fminsearch' para minimizar el error de ajuste.
            [P, ~, ~] = fminsearch(err, p0);
            
            % Los parámetros:
            % P(1): posición ajustada del pico (mu).
            % P(2): anchura ajustada del pico (sigma).
            % P(3): amplitud ajustada.
            % P(4): offset ajustado.
            
            % Calcular los valores ajustados de la función gaussiana con los parámetros optimizados.
            ajusy = calcularAjusteGaussiano(app, P(1), P(2), P(3), P(4), posiciones);
            
            % Graficar los datos originales y la curva ajustada.
            figure
            plot(posiciones, ctasMedidas, '*') % Datos medidos.
            hold on
            plot(posiciones, ajusy, 'LineWidth', 3) % Curva ajustada.
            hold off
            
            % Si la anchura ajustada (P(2)) es muy diferente de la inicial (más del doble o menor a 0.1),
            % se mantiene la anchura inicial p2 para evitar un ajuste incorrecto.
            if or(P(2) > 2 * p2, P(2) < 0.1)
                anchuraAjustada = 2*sqrt(2*log(2))*p2;
            else
                anchuraAjustada = 2*sqrt(2*log(2))*P(2);
            end
            
            % Si la posición ajustada (P(1)) está fuera del rango de las posiciones medidas,
            % se mantiene la posición inicial p1.
            if or(P(1) > posiciones(end), P(1) < posiciones(end))
                posAjustada = p1;
            else
                posAjustada = P(1);
            end
        end
        
        
        function ajusteGaussiano = calcularAjusteGaussiano(app, media, desviacion, amplitud, offset, posiciones)
            
            % Cálculo de la función gaussiana:
            ajusteGaussiano = offset + amplitud .* (1/(sqrt(2*pi) * desviacion)) .* ...
                exp(-0.5 * ((posiciones - media) / desviacion).^2);
        end
        
        function actualizarBarraColor(app, tarea)
            
            switch tarea
                case 'scan'
                    matrizCuentas = app.countsMatrix;
                case 'zoom'
                    matrizCuentas = app.countsZoom;
            end
            
            app.minColorbar = min(matrizCuentas,[],"all");
            app.maxColorbar = max(matrizCuentas,[],"all");
            
            if app.minColorbar == 0 && app.maxColorbar == 0
                % Establecer límites por defecto
                caxis(app.UIAxes, [-1, 1]);
                
                app.MinColorbarEditField.Value = -1;
                app.MaxColorbarEditField.Value = 1;
            elseif app.maxColorbar == app.maxColorbar
                caxis(app.UIAxes, [0, app.maxColorbar]);
                
                app.MinColorbarEditField.Value = 0;
                app.MaxColorbarEditField.Value = app.maxColorbar;
            else
                % Establecer límites por defecto
                caxis(app.UIAxes, [app.maxColorbar, app.maxColorbar]);
                
                app.MinColorbarEditField.Value = app.minColorbar;
                app.MaxColorbarEditField.Value = app.maxColorbar;
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
             %===============================================================
            % Se iniciaran sesiones principales al inciar la aplicación
            % para evitar conflictos al ejecutar distintas funcionalidades.
            %===============================================================
            
            % Crear sesión para generación de pulsos a usar como reloj
            %iniciarSesionReloj(app,'traza')
            
            % Crear sesión para medir la traza
            %iniciarSesionTraza(app)
            
            % Crear sesión para manejar piezo
            iniciarSesionCoordenada(app,'x')
            iniciarSesionCoordenada(app,'y')
            iniciarSesionCoordenada(app,'z')
            
            %======================================================================
            %======================================================================
            
            
            actualizarVoltajes(app)
            
            app.countsMatrix = zeros(80,80);
            app.xCoordCts = linspace(0,80,81)';
            app.yCoordCts = linspace(0,80,81)';
            
            dataLoadedCallback(app)
            
            % Inicializar timer
            tiempoDeEspera(app)
            
            % configurar diponibilidad de interacciones en la GUI
            setAppViewState(app, 'configuration');
            drawnow
        end

        % Button pushed function: StartTraceButton
        function StartTraceButtonPushed(app, event)
            
            if ~(isempty(app.DAQSession))
                % Verificar si los datos serán guardados
                
                if app.LogRequested
                    % If Log data to file switch is on
                    % Create and open temporary binary file to log data to disk
                    app.TempFilename = tempname;
                    app.TempFile = fopen(app.TempFilename, 'w');
                end
                
                %======================================================================
                %===================== CONF. GRÁFICO TRAZA ======================
                
                % Setear buffers como arreglos vacíos previo a usarlos
                app.DataFIFOBuffer = [];
                app.TimestampsFIFOBuffer = [];
                
                % Crear figura externa a la app para mostrar traza
                app.traceFigure = figure('Name','Traza', 'Position', [100, 100, 800, 600], ...
                         'CloseRequestFcn', @(src, event) closeTrazaCallback(app, src, event));
                
                % Obtener el handle a los ejes de la figura
                app.axesTrace = axes(app.traceFigure);
                %Hacer plot sin datos
                app.lineTrace = plot(app.axesTrace, NaN, 'LineWidth',3,"Color",'k');
                
                % Conf. título y etiquetas
                title(app.axesTrace, 'Traza');
                xlabel(app.axesTrace, 'u.a');
                ylabel(app.axesTrace, 'cuentas/s');
                
                xlim(app.axesTrace,[0, app.NBufferEditField.Value]);
                set(app.axesTrace, 'FontSize', 32);
                
                %======================================================================
                %======================================================================
                
                iniciarSesionReloj(app,'traza')
                
                % Crear listener e iniciar en 2do plano sesion de adquisición
                app.TraceAvailableListener = addlistener(app.DAQSession, 'DataAvailable', ...
                    @(src,event) dataAvailable_Callback(app, src, event));
                
                startBackground(app.DAQSession);
                %======================================================================
                %======================================================================
                
                % Modificar apariencia de botones y switches en UI
                
                setAppViewState(app, 'acquisition');
                
            else
                iniciarSesionTraza(app)
                StartTraceButtonPushed(app,event)
            end            

        end

        % Value changed function: StopTraceButton
        function StopTraceButtonValueChanged(app, event)
            
            % Detener adquisición de datos
            stop(app.DAQSession)
            
            % Eliminar e inicializar listener a array vacío
            delete(app.TraceAvailableListener);
            app.TraceAvailableListener = [];
            
            % Setear handles de gráfico de traza a array vacios
            delete(app.traceFigure)
            app.traceFigure = [];
            app.axesTrace = [];
            app.lineTrace = [];
            
            % Activar interacción con componentes de la UI
            if ~(isempty(app.DAQSesionCoordX))
                setAppViewState(app, 'configuration');
                
            else
                setAppViewState(app, 'scannerFinished');
            end
            
            
            % Verificar si hay que guardar archivos
            
            if app.LogRequested
                % Log data to file switch is on
                % Save logged data to MAT file (unless the user clicks Cancel in the "Save As" dialog)
                
                % Close temporary binary file
                fclose(app.TempFile);
                
                
                % Gather metadata in preparation for saving to MAT file
                % Store relevant Daq device info
                metadata.IntegrationTime = app.IntTimemsEditField.Value;
                metadata.NotifyScans = app.NotifyScansSpinner.Value;
                metadata.ClkFreq = 1000/(app.IntTimemsEditField.Value);
                metadata.TriggerTime = datetime(app.TriggerTime, 'ConvertFrom', 'datenum', 'TimeZone', 'local');
                
                % Open "Save As" to request destination MAT file path and file name from user
                [filename, pathname] = uiputfile({'*.mat'}, 'Save as',...
                    fullfile(app.Filepath, datestr(datetime('now'), 'yyyy-mm-dd_HHMMSS_TRA')));
                
                if ~(isequal(filename,0) || isequal(pathname,0))
                    % User specified a file name in a folder with write permission
                    app.Filename = datestr(datetime('now'), 'yyyy-mm-dd_HHMMSS_TRA');
                    app.Filepath = pathname;
                    cancelSaveAs = false;
                else
                    %  User clicked Cancel in "Save As" dialog
                    cancelSaveAs = true;
                end
                
                if ~cancelSaveAs
                    % Convert data from binary file to MAT file
                    matFilepath = fullfile(app.Filepath, datestr(datetime('now'), 'yyyy-mm-dd_HHMMSS_TRA'));
                    
                    
                    numColumns = 2;
                    binFile2MAT(app, app.TempFilename, matFilepath, numColumns, metadata);
                    
                else
                    % User clicked Cancel in "Save As" dialog
                    % Inform user that data has not been saved
                end
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            %Interacción para cerrar aplicación y usar callback para cerrar
            
            isAcquiring = ~isempty(app.DAQSession) && app.DAQSession.IsRunning;
            if isAcquiring
                question = 'Abort acquisition and close app?';
                
            else
                % Acquisition is stopped
                question = 'Close app?';
            end
            
            uiconfirm(app.UIFigure,question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,isAcquiring));
        end

        % Value changed function: SavingDataSwitch
        function SavingDataSwitchValueChanged(app, event)
            updateLogdatatofileSwitchComponents(app)
        end

        % Value changed function: IntTimemsEditField
        function IntTimemsEditFieldValueChanged(app, event)
            
            % Ajustar datos necearios para notificar en listener de
            % adquisicion dependiendo de tiempo de integración ingresado
            % por usuario
            
            dT = app.IntTimemsEditField.Value;
            notify = app.NotifyScansSpinner.Value;
            
            prod = dT * notify;
            
            if(prod < 100)
                app.NotifyScansSpinner.Value = round(100/dT) + 1;
            
            end
            
        end

        % Value changed function: NotifyScansSpinner
        function NotifyScansSpinnerValueChanged(app, event)
            
            % Ajustar tiempo de integración de adquisición de datos
            % dependiendo de datos necesarios para notificar en listener
            % ingresados por usuario
            
            dT = app.IntTimemsEditField.Value;
            notify = app.NotifyScansSpinner.Value;
            
            prod = dT * notify;
            
            if(prod < 100)
                app.IntTimemsEditField.Value = ceil((100/notify)*10)/10 ;
            
            end
        end

        % Button pushed function: LoadFigureButton
        function LoadFigureButtonPushed(app, event)
            
            
            if ~(isempty(app.DAQSesionCoordX))
                % Definir la carpeta actual
                currentFolder = pwd;
                
                % Definir el filtro de archivos para archivos .mat
                fileFilter = {'*.mat', 'Archivos MAT (*.mat)'};
                
                % Abrir el diálogo de selección de archivos
                [file, path] = uigetfile(fileFilter, 'Seleccionar un archivo MAT', currentFolder);
                
                % Verificar si se seleccionó un archivo
                if isequal(file, 0)
                    disp('Se ha cancelado cargar una figura');
                else
                    fullPath = fullfile(path, file);
                    disp(['Has seleccionado: ', fullPath]);
                    
                    % Cargar datos escaneados
                    struct = load(fullPath);
                    
                    % Identificar si el archivo tiene la estructura 'scan' o 'zoom'
                    if isfield(struct, 'scan')
                        dataStruct = struct.scan;
                    elseif isfield(struct, 'zoom')
                        dataStruct = struct.zoom;
                    else
                        disp('El archivo no contiene las estructuras esperadas.');
                        return;
                    end
                    
                    % Extraer coordenadas y datos de la estructura correspondiente
                    app.xCoordCts = dataStruct.data.x;
                    app.yCoordCts = dataStruct.data.y;
                    app.countsMatrix = dataStruct.data.c;
                    
                    % Leer voltajes actuales en piezo para cada coordenada
                    actualizarVoltajes(app)
                    
                    % Moverse a puntos donde se fijó offset para scan/zoom
                    app.voltX = dataStruct.metadataScan.posX * 0.125;
                    app.voltY = dataStruct.metadataScan.posY * 0.125;
                    app.voltZ = dataStruct.metadataScan.posZ * 0.125;
                    
                    desplazarCoordenada(app,'x')
                    desplazarCoordenada(app,'y')
                    desplazarCoordenada(app,'z')
                    
                    % Actualizar voltajes actuales
                    actualizarVoltajes(app)
                    
                    % Hacer reset a colorbar
                    maxActual = max(app.countsMatrix,[],'all');
                    minActual = min(app.countsMatrix,[],'all');
                    
                    if maxActual == minActual
                        % Establecer límites por defecto
                        caxis(app.UIAxes, [0, 1000]);
                    else
                        % Establecer límites por defecto
                        caxis(app.UIAxes, [minActual, maxActual]);
                    end
                    
                    % Setear pestañas de colorbar a 0
                    app.MinColorbarEditField.Value = 0;
                    app.MaxColorbarEditField.Value = 0;
                    
                    % Llamar función que hace gráfico y configura listeners
                    dataLoadedCallback(app);
                    
                    % Actualizar valores del scan
                    app.ScanNpixelsEditField.Value = dataStruct.metadataScan.nPixels;
                    app.ScanFreqEditField.Value = dataStruct.metadataScan.frecuencia;
                    app.StimemsEditField.Value = dataStruct.metadataScan.settlingTime;
                    app.CtimemsEditField.Value = dataStruct.metadataScan.countsTime;
                    app.XoffsetEditField.Value = dataStruct.metadataScan.posX;
                    app.YoffsetEditField.Value = dataStruct.metadataScan.posY;
                    app.ZoffsetEditField.Value = dataStruct.metadataScan.posZ;
                    app.ScanSizeumEditField.Value = dataStruct.metadataScan.scanSize;
                    
                    app.SampleNameEditField.Value = dataStruct.metadataMuestra.muestra;
                    app.FilterNameEditField.Value = dataStruct.metadataMuestra.filtro;
                    app.DetectorEditField.Value = dataStruct.metadataMuestra.detector;
                    app.PoweruWEditField.Value = dataStruct.metadataMuestra.potencia;
                    app.WavelengthnmEditField.Value = dataStruct.metadataMuestra.longOnda;
                    
                end
            else
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                LoadFigureButtonPushed(app, event);
            end


        end

        % Value changed function: NBufferEditField
        function NBufferEditFieldValueChanged(app, event)
            %supLim = app.NBufferEditField.Value;
            %xlim(app.axesTrace, [0, supLim])
        end

        % Value changed function: RightXButton
        function RightXButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQSesionCoordX))
                % Leer voltajes actuales en piezo
                actualizarVoltajes(app)
                
                % Obtener coordenada X actual
                x = app.currentVoltX * 8;
                
                % Obtener desplazamiento en nm para coord. X
                rightX = x + app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if rightX >= min(app.xCoordCts) && rightX <= max(app.xCoordCts)
                    
                    % Obtener voltaje asociado a dezplazamiento
                    app.voltX = rightX * 0.125;
                    
                    % Mover el piezo solo en coordenada X
                    outputSingleScan(app.DAQSesionCoordX, app.voltX);
                    
                    % Actualizar voltaje en UI
                    actualizarVoltajes(app)
                    
                    % Actualizar las líneas de coordenadas
                    set(app.hXLine, 'Value', rightX);
                end
            else
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                
                RightXButtonValueChanged(app, event)
            end
            
        end

        % Value changed function: LeftXButton
        function LeftXButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQSesionCoordX))
                % Leer voltajes actuales en piezo
                actualizarVoltajes(app)
                
                % Obtener coordenada X actual
                x = app.currentVoltX * 8;
                
                % Obtener desplazamiento en nm para coord. X
                LeftX = x - app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if LeftX >= min(app.xCoordCts) && LeftX <= max(app.xCoordCts)
                    
                    % Obtener voltaje asociado a dezplazamiento
                    app.voltX = LeftX * 0.125;
                    
                    % Mover el piezo solo en coordenada X
                    outputSingleScan(app.DAQSesionCoordX, app.voltX);
                    
                    % Actualizar voltaje en UI
                    actualizarVoltajes(app)
                    
                    % Actualizar las líneas de coordenadas
                    set(app.hXLine, 'Value', LeftX);
                end
            else
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                
                LeftXButtonValueChanged(app, event)
            end
           
            
        end

        % Value changed function: UpYButton
        function UpYButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQSesionCoordY))
                % Leer voltajes actuales en piezo
                
                actualizarVoltajes(app)
                
                % Obtener coordenada Y actual
                
                y = app.currentVoltY * 8;
                
                % Obtener desplazamiento en nm para coord. Y
                
                upY = y - app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if upY >= min(app.yCoordCts) && upY <= max(app.yCoordCts)
                    
                    % Obtener voltaje asociado a dezplazamiento
                    app.voltY = upY * 0.125;
                    
                    % Mover el piezo solo en coordenada Y
                    outputSingleScan(app.DAQSesionCoordY, app.voltY);
                    
                    % Actualizar voltaje en UI
                    actualizarVoltajes(app)
                    
                    % Actualizar las líneas de coordenadas
                    set(app.hYLine, 'Value', upY);
                end
            else
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                
                UpYButtonValueChanged(app, event)
            end
           
            
        end

        % Value changed function: DownYButton
        function DownYButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQSesionCoordY))
                % Leer voltajes actuales en piezo
                actualizarVoltajes(app);
                
                % Obtener coordenada Y actual
                
                y = app.currentVoltY * 8;
                
                % Obtener desplazamiento en nm para coord. Y
                
                DownY = y + app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if DownY >= min(app.yCoordCts) && DownY <= max(app.yCoordCts)
                    
                    % Obtener voltaje asociado a dezplazamiento
                    app.voltY = DownY/8;
                    
                    % Mover el piezo solo en coordenada Y
                    outputSingleScan(app.DAQSesionCoordY, app.voltY);
                    
                    % Actualizar voltaje en UI
                    actualizarVoltajes(app)
                    
                    % Actualizar las líneas de coordenadas
                    set(app.hYLine, 'Value', DownY);
                end
            else
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                
                DownYButtonValueChanged(app, event)
            end
           
            
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            
            if ~(isempty(app.DAQSesionCoordZ))
                % Update current voltage
                actualizarVoltajes(app)
                
                % Get the current value from slider
                zChanging = event.Value;
                
                % Update coord. Z in UI
                app.ZcoordEditField.Value = zChanging;
                
                % Set the final voltage to approach it
                app.voltZ = zChanging * 0.125;
                
                desplazarCoordenada(app,'z')
                % Update voltage values
                actualizarVoltajes(app)
            else
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                
                ZSliderValueChanging(app, event)
            end
                
        end

        % Value changed function: IncZButton
        function IncZButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQSesionCoordZ))
                % Leer voltajes actuales en piezo
                
                actualizarVoltajes(app);
                
                % Obtener coord. Z actual
                
                z = app.currentVoltZ * 8;
                
                % Obtener desplazamiento en nm para coord. Z
                
                incZ = z + app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if incZ >= 0 && incZ <= 80
                    
                    % Obtener voltaje asociado a dezplazamiento
                    app.voltZ = incZ * 0.125;
                    
                    % Mover el piezo solo en coordenada Z
                    outputSingleScan(app.DAQSesionCoordZ, app.voltZ);
                    
                    % Actualizar voltaje en UI
                    actualizarVoltajes(app)
                    
                    % Actualizar valor slider
                    actualizarSliderZ(app,incZ);
                end
            else
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                
                ZSliderValueChanging(app, event)
            end
       
            
        end

        % Value changed function: DecZButton
        function DecZButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQSesionCoordZ))
                % Leer voltajes actuales en piezo
                actualizarVoltajes(app)
                
                % Obtener coord. Z actual
                
                z = app.currentVoltZ * 8;
                
                % Obtener desplazamiento en nm para coord. Z
                
                decZ = z - app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if decZ >= 0 && decZ <= 80
                    
                    % Obtener voltaje asociado a dezplazamiento
                    app.voltZ = decZ * 0.125 ;
                    
                    % Mover el piezo solo en coordenada Z
                    outputSingleScan(app.DAQSesionCoordZ, app.voltZ);
                    
                    % Actualizar voltaje en UI
                    actualizarVoltajes(app)
                    
                    % Actualizar valor slider
                    actualizarSliderZ(app,decZ);
                    
                end
            else
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                
                ZSliderValueChanging(app, event)
            end
            
        end

        % Value changed function: Goto000Button
        function Goto000ButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQSesionCoordX) || isempty(app.DAQSesionCoordY) || isempty(app.DAQSesionCoordZ))
                % Leer voltajes actuales en piezo
                actualizarVoltajes(app)
                
                % Setear como voltaje final el 0,0,0
                
                app.voltX = 0;
                app.voltY = 0;
                app.voltZ = 0;
                
                app.ZSlider.Limits = [0 80];
                app.ZSlider.Value = 0;
                
                % Desplazar cada coordenada al 0,0,0
                
                desplazarCoordenada(app,'x')
                desplazarCoordenada(app,'y')
                desplazarCoordenada(app,'z')
                
                % Actualizar valores en UI
                
                actualizarVoltajes(app)
                
                
                set(app.hXLine, 'Value', 0);
                set(app.hYLine, 'Value', 0);
            else
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                
                Goto000ButtonValueChanged(app, event)
            end
                
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            %============================================================================
            %============ E L I M I N A R  T R A Z A  P R I N C I P A L =================
            % Eliminar gráfico de traza en caso de que esté abierto
            
            % Setear handles de gráfico de traza a array vacios
            delete(app.traceFigure)
            app.traceFigure = [];
            app.axesTrace = [];
            app.lineTrace = [];
            
            
            %======================================================================
            %================= C R E A R  T R A Z A  S C A N ======================
            
            % Setear buffers como arreglos vacíos previo a usarlos
            app.DataFIFOBufferTrazaScan = [];
            app.TimestampsFIFOBufferTrazaScan = [];
            
            
            % Crear figura externa a la app para mostrar traza
            app.traceScanFigure = figure('Name','Traza Scan', 'Position', [100, 100, 800, 600]);
            
            % Obtener el handle a los ejes de la figura
            app.axesScanTrace = axes(app.traceScanFigure);
            %Hacer plot sin datos
            app.lineScanTrace = plot(app.axesScanTrace, NaN, 'LineWidth',3,"Color",'k');
            
            % Conf. título y etiquetas
            title(app.axesScanTrace, 'Traza Scan');
            xlabel(app.axesScanTrace, 'u.a');
            ylabel(app.axesScanTrace, 'cuentas/s');
            
            xlim(app.axesScanTrace,[0, app.NBufferEditField.Value]);
            set(app.axesScanTrace, 'FontSize', 32);
            
            % Iniciar sesion de traza scan
            iniciarTrazaScan(app)
            
            % Crear listener e iniciar en 2do plano sesion de adquisición
            app.trazaScanListener = addlistener(app.DAQTrazaScan, 'DataAvailable', ...
                @(src,event) trazaScanCallback(app, src, event));
            
            startBackground(app.DAQTrazaScan);
            
            %============================================================================
            %==================== P A R A M E T R O S  S C A N ==========================
            
            % Cantidad de elementos minimos en output voltaje
            dimOutput = app.ScanFreqEditField.Value * 0.5;
            
            % Tiempo que consideramos que el piezo se mueve
            settlingTime = (app.StimemsEditField.Value)/1000;
            
            % Tiempo durante el cual se considerarán cuentas válidas
            integrationTime = (app.CtimemsEditField.Value)/1000;
            
            % Cantidad de elementos en voltaje constante
            constVoltIntervalo = int64(app.ScanFreqEditField.Value * (settlingTime + integrationTime));
            % Elementos a repetir para esperar al volver al origen
            tiempoEsperaVueltaOrigen = 0.048 * ( 2 * dimOutput);
            
            % Cantidad de puntos a escanear
            nPuntos = app.ScanNpixelsEditField.Value;
            
            % Total de elementos en output considerando tramo que devuelve
            % al piezo al (0,0)
            totalElementosOutput = (constVoltIntervalo + 1) * nPuntos * nPuntos + tiempoEsperaVueltaOrigen * nPuntos;
            
            disp(totalElementosOutput)
            
            % Verificar que la salida de voltaje tenga los elementos
            % minimos
            if(totalElementosOutput < dimOutput)
                % Modificar nPuntos dados settlingTime e integrationTime
                nPuntos = ceil(sqrt(0.5 * (settlingTime + integrationTime)^-1));
                totalElementosOutput = (constVoltIntervalo + 1) * nPuntos * nPuntos + tiempoEsperaVueltaOrigen * nPuntos;
                
                app.ScanNpixelsEditField.Value = nPuntos;
            end


            % MOVER PIEZO A OFFSET DE Z
            app.voltZ = app.ZoffsetEditField.Value * 0.125;
            desplazarCoordenada(app,'z')
            
            
            % Configurar tiempo inicial
            initialTime = double( ((settlingTime + integrationTime)*totalElementosOutput)/constVoltIntervalo )  + 8;
            app.RemainingTimeScansEditField.Value = initialTime;
            
            % Crear el timer
            app.CountdownTimer = timer(...
                'ExecutionMode', 'fixedRate', ...  % El timer se ejecuta a intervalos regulares
                'Period', 1, ...  % Periodo de 1 segundo
                'TasksToExecute', initialTime, ...  % Número de veces que se ejecutará
                'TimerFcn', @(~,~) actualizarCountdown(app));  % Función que se ejecuta en cada tick del timer
            
            % Iniciar el timer
            start(app.CountdownTimer);
            
            disp(1)
            disp(class(app.DAQScanSession))
            % Iniciar sesion que realiza scan
            iniciarSesionScan(app)
            setAppViewState(app, 'scanning')
            disp(2)
            disp(class(app.DAQScanSession))


            % Limpiar listener del scan
            delete(app.scanListener);
            app.scanListener = [];


            %============================================================================
            %======================== C O O R D S.  S C A N =============================
            
            % Obtener puntos en los cuales se centra el scan
            centroScanX = app.XoffsetEditField.Value;
            centroScanY = app.YoffsetEditField.Value;
            
            % Offset del scan
            scanOffset = app.ScanSizeumEditField.Value * 0.5;
            
            % Obtener puntos iniciales del scan
            coordInicialX = centroScanX - scanOffset;
            coordInicialY = centroScanY - scanOffset;
            
            % Obtener puntos en los cuales debería terminar el scan
            coordFinalX = centroScanX + scanOffset;
            coordFinalY = centroScanY + scanOffset;
            
            % Actualizar voltajes actuales en piezo
            updatePiezoVoltage(app)


            disp(3)

            disp(class(app.DAQScanSession))
            
            
            if (coordInicialX >= 0 && coordInicialY >= 0) && (coordFinalX <= 80 && coordFinalY <= 80)
                
                % Transformar posiciones iniciales a voltaje
                voltInicialX = coordInicialX/8;
                voltInicialY = coordInicialY/8;
                
                % Transformar posiciones finales a voltaje
                voltFinalX = coordFinalX/8;
                voltFinalY = coordFinalY/8;
                
                % Setear voltajes a los que iremos en el pto inicial 
                app.voltX = voltInicialX;
                app.voltY = voltInicialY;
                
                disp(4)
                disp(class(app.DAQScanSession))
                % Desplazarse a las coordenadas iniciales ingresadas
                desplazarScan(app)
                disp(5)
                disp(class(app.DAQScanSession))
                
                
                disp(6)
                % Una vez adquiridos datos totales se activará listener
                app.DAQScanSession.NotifyWhenDataAvailableExceeds = totalElementosOutput;
                
                % Arreglo de voltajes a seguir en eje X
                voltPathX = linspace(voltInicialX, voltFinalX, nPuntos)';
                
                % Arreglo de voltajes a seguir en eje Y
                voltPathY = linspace(voltInicialY, voltFinalY, nPuntos)';
                
                % Guardar caminos de voltaje en coordenadas
                app.xCoordCts = voltPathX * 8;
                app.yCoordCts = voltPathY * 8;
                
                % Voltaje de ida en X
                subidaVoltX = repelem(voltPathX, constVoltIntervalo);
                
                % Voltaje de vuelta en X
                bajadaVoltX = flip(voltPathX);
                % Repetir primer y ultimo elemento en bajada de voltaje
                repUltElemento = repelem(bajadaVoltX(end),tiempoEsperaVueltaOrigen)';
                
                bajadaVoltX = [bajadaVoltX;repUltElemento];
                
                
                % Vector con voltajes concatenados
                outVoltajeX = [subidaVoltX; bajadaVoltX];
                
                % Matriz con total de voltajes para X
                outVoltajeX = repmat(outVoltajeX, nPuntos, 1);
                
                
                % Periodo voltaje X
                periodoVoltX = length(outVoltajeX)/nPuntos;
                
                % Output de voltajes a entregar en el piezo en dimension Y
                outVoltajeY = repelem(voltPathY, periodoVoltX);
                
                disp(length(outVoltajeY))
                disp(length(outVoltajeX))
                
                
                disp(7)
                disp(class(app.DAQScanSession))
                % Poner voltajes a la cola
                
                %============================================================================
                %====================== P A R A M.  C U E N T A S ===========================
                
                % Elementos en cada intervalo de settling time
                intervaloSettlingTime = int64(settlingTime * app.ScanFreqEditField.Value);
                
                % Elementos en intervalo de cuentas
                intervaloCuentas = int64(integrationTime * app.ScanFreqEditField.Value);
                
                % Cantidad de elementos hasta que X vuelve al 0
                periodoIntervalo = (intervaloSettlingTime + intervaloCuentas) * nPuntos;
                
                % indices a eliminar; donde se vuelve al inicio
                initNoCtsIdx = [periodoIntervalo + 1:periodoVoltX:totalElementosOutput]';
                
                % Indice donde X termina de volver al 0
                finNoCtsIdx = [periodoVoltX:periodoVoltX:totalElementosOutput]';
                
                % Indices concatenados a eliminar
                idxNoCts = [initNoCtsIdx finNoCtsIdx];
                
                % Dar vuelta los indices para eliminar desde el indice mayor
                idxNoCts = flip(idxNoCts);
                
                clear initNoCtsIdx finNoCtsIdx periodoIntervalo
                
                totalElemCts = (constVoltIntervalo) * nPuntos * nPuntos;
                
                % Intervalos donde estan las cuentas
                initCtsIdx = [intervaloSettlingTime:constVoltIntervalo:totalElemCts]';
                finCtsIdx = [constVoltIntervalo-1:constVoltIntervalo:totalElemCts]';
                
                idxCts = [initCtsIdx finCtsIdx];
                
                clear initCtsIdx finCtsIdx
                
                % Crear el listener para generar matriz
                app.scanListener = addlistener(app.DAQScanSession, 'DataAvailable', ...
                    @(src, event) scanProcessData(app, event, idxNoCts, idxCts, 'scan'));
                
                queueOutputData(app.DAQScanSession,[outVoltajeX, outVoltajeY]);
                pause(1)
                disp("hola")
                startBackground(app.DAQScanSession);


            else
                beep
                errordlg('Scan out of range! Please adjust the coordinate values.', 'Error');
            end


        end

        % Value changed function: SetoffsetButton
        function SetoffsetButtonValueChanged(app, event)
            
            % Marcar bandera de offset presionado
            app.transCoords = true;
            
            % Setear la posición actual para hacer un scan a su alrededor
            app.XoffsetEditField.Value = app.XcoordEditField.Value;
            app.YoffsetEditField.Value = app.YcoordEditField.Value;
            app.ZoffsetEditField.Value = app.ZcoordEditField.Value;
        end

        % Button pushed function: StopscanButton
        function StopscanButtonPushed(app, event)
            if ~(isempty(app.DAQScanSession))
                % Detener la adquisición de datos
                stop(app.DAQScanSession)
                
                % Eliminar listener
                delete(app.scanListener);
                app.scanListener = [];
                
                % detener timer
                if ~(isempty(app.CountdownTimer))
                    stop(app.CountdownTimer); 
                    delete(app.CountdownTimer);
                end
                
                
                % Setear contador a cero
                app.RemainingTimeScansEditField.Value = 0;
                
                % Restablecer el estado de la aplicación
                setAppViewState(app, 'scannerStopped');
            end
        end

        % Button pushed function: ActPanelButton
        function ActPanelButtonPushed(app, event)
            if ~(isempty(app.DAQScanSession))
                
                %=========================================================================
                %========== E L I M I N A R   T R A Z A   D E L   S C A N ================
                % Detener adquisición de datos
                stop(app.DAQTrazaScan)
                
                % Eliminar e inicializar listener a array vacío
                delete(app.trazaScanListener);
                app.trazaScanListener = [];
                
                % Setear handles de gráfico de traza a array vacios
                delete(app.traceScanFigure)
                app.traceScanFigure = [];
                app.axesScanTrace = [];
                app.lineScanTrace = [];
                
                %====================================================
                %========== A C T I V A R  P A N E L ================
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                
                actualizarVoltajes(app)
                
                setAppViewState(app, 'configuration')
                
                
                % Inicializar líneas de coordenadas
                hold(app.UIAxes, 'on');
                app.hXLine = xline(app.UIAxes, app.XcoordEditField.Value, 'k');
                app.hYLine = yline(app.UIAxes, app.YcoordEditField.Value, 'k');
                hold(app.UIAxes, 'off');
    
                % Crear lsiteners de interaccion con figura para establecer
                % coordenadas
                set(app.matrixPlot, 'ButtonDownFcn', @(src, event)clickDown(app, src, event));
                set(app.UIFigure, 'WindowButtonMotionFcn', @(src, event)whileClickDown(app, src, event));
                set(app.UIFigure, 'WindowButtonUpFcn', @(src, event)clickUp(app, src, event));
                
                
                % Desplazar coordenadas para evitar saltos
                RightXButtonValueChanged(app,[])
                pause(0.1)
                LeftXButtonValueChanged(app,[])
                pause(0.1)
                UpYButtonValueChanged(app,[])
                pause(0.1)
                DownYButtonValueChanged(app,[])
            end
        end

        % Value changed function: MinZEditField
        function MinZEditFieldValueChanged(app, event)
            value = app.MinZEditField.Value;
            app.ZSlider.Limits = [value max(app.ZSlider.Limits)];
        end

        % Value changed function: MaxZEditField
        function MaxZEditFieldValueChanged(app, event)
            value = app.MaxZEditField.Value;
            app.ZSlider.Limits = [min(app.ZSlider.Limits) value];
            
        end

        % Button pushed function: SaveScanButton
        function SaveScanButtonPushed(app, event)
            
            guardarDatosScan(app,'scan')                    
           
        end

        % Value changed function: MinColorbarEditField
        function MinColorbarEditFieldValueChanged(app, event)
            
            
            minUsuario = app.MinColorbarEditField.Value;
            
            % Limites actuales en colorbar
            colorLimits = caxis(app.UIAxes);
            
            maxActual = colorLimits(2);
            
            if minUsuario > maxActual
                beep
                errordlg('Error entering limit! The minimum value on the scale cannot be greater than the current maximum. Please adjust the values.', 'Error');
                return;
            end
            
            try
                % Establecer nuevo minimo
                caxis(app.UIAxes, [minUsuario, maxActual]);
            catch ME
                error(ME.message,'Unexpected error.')
            end


        end

        % Value changed function: MaxColorbarEditField
        function MaxColorbarEditFieldValueChanged(app, event)
            maxUsuario = app.MaxColorbarEditField.Value;
            
            % Limites actuales en colorbar
            colorLimits = caxis(app.UIAxes);
            
            minActual = colorLimits(1);
            
            if maxUsuario < minActual
                beep
                errordlg('Error entering limit! The maximum value on the scale cannot be less than the current maximum. Please adjust the values.', 'Error');
                return;
            end
            
            try
                % Establecer nuevo minimo
                caxis(app.UIAxes, [minActual, maxUsuario]);
            catch ME
                error(ME.message,'Unexpected error.')
            end
        end

        % Value changed function: CtimemsEditField, 
        % ScanFreqEditField, ScanNpixelsEditField, StimemsEditField
        function ScanNpixelsEditFieldValueChanged(app, event)
            tiempoDeEspera(app)
            
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            
            % Eliminar gráfico de traza en caso de que esté abierto
            
            % Setear handles de gráfico de traza a array vacios
            delete(app.traceFigure)
            app.traceFigure = [];
            app.axesTrace = [];
            app.lineTrace = [];
            
            %============================================================================
            %==================== P A R A M E T R O S  S C A N ==========================
            
            % Cantidad de elementos minimos en output voltaje
            dimOutput = app.ScanFreqEditField.Value * 0.5;
            
            % Tiempo que consideramos que el piezo se mueve
            settlingTime = (app.StimemsEditField.Value)/1000;
            
            % Tiempo durante el cual se considerarán cuentas válidas
            integrationTime = (app.CtimemsEditField.Value)/1000;
            
            % Cantidad de elementos en voltaje constante
            constVoltIntervalo = int64(app.ScanFreqEditField.Value * (settlingTime + integrationTime));
            
            % Elementos a repetir para esperar al volver al origen
            tiempoEsperaVueltaOrigen = 0.048 * ( 2 * dimOutput);
            
            % Cantidad de puntos a escanear
            nPuntos = app.ScanNpixelsEditField.Value;
            
            % Total de elementos en output considerando tramo que devuelve
            % al piezo al (0,0)
            totalElementosOutput = (constVoltIntervalo + 1) * nPuntos * nPuntos + tiempoEsperaVueltaOrigen * nPuntos;
            
            disp(totalElementosOutput)
            
            % Verificar que la salida de voltaje tenga los elementos
            % minimos
            if(totalElementosOutput < dimOutput)
                % Modificar nPuntos dados settlingTime e integrationTime
                nPuntos = ceil(sqrt(0.5 * (settlingTime + integrationTime)^-1));
                totalElementosOutput = (constVoltIntervalo + 1) * nPuntos * nPuntos;
                
                app.ScanNpixelsEditField.Value = nPuntos;
            end
            
            %============================================================================
            %============================== T I M E R ===================================
            
            % Configurar tiempo inicial
            initialTime = double( ((settlingTime + integrationTime)*totalElementosOutput)/constVoltIntervalo )  + 8;
            app.RemainingTimeScansEditField.Value = initialTime;
            
            % Crear el timer
            app.CountdownTimer = timer(...
                'ExecutionMode', 'fixedRate', ...  % El timer se ejecuta a intervalos regulares
                'Period', 1, ...  % Periodo de 1 segundo
                'TasksToExecute', initialTime, ...  % Número de veces que se ejecutará
                'TimerFcn', @(~,~) actualizarCountdown(app));  % Función que se ejecuta en cada tick del timer
            
            % Iniciar el timer
            start(app.CountdownTimer);
            
            disp(1)
            disp(class(app.DAQScanSession))
            % Iniciar sesion que realiza scan
            iniciarSesionScan(app)
            setAppViewState(app, 'scanning')
            disp(2)
            disp(class(app.DAQScanSession))
            
            % Limpiar listener del scan
            delete(app.scanListener);
            app.scanListener = [];
            
            %============================================================================
            %======================== C O O R D S.  S C A N =============================
            
            % Obtener puntos en los cuales se centra el scan
            centroScanX = app.XcoordEditField.Value;
            centroScanY = app.YcoordEditField.Value;
            
            % Offset del zoom. Predeterminado en 10 um
            scanOffset = app.ZoomSizeumEditField.Value * 0.5;
            
            % Obtener puntos iniciales del scan
            coordInicialX = centroScanX - scanOffset;
            coordInicialY = centroScanY - scanOffset;
            
            % Obtener puntos en los cuales debería terminar el scan
            coordFinalX = centroScanX + scanOffset;
            coordFinalY = centroScanY + scanOffset;
            
            % Actualizar voltajes actuales en piezo
            updatePiezoVoltage(app)
            
            % ACTUALIZAR VARIABLES DEL SISTEMA RELACIONADAS AL ZOOM
            app.xOffSetZoom = centroScanX;
            app.yOffSetZoom = centroScanY;
            app.zOffSetZoom = app.ZcoordEditField.Value;
            app.sizeZoom = app.ZoomSizeumEditField.Value;


            disp(3)

            disp(class(app.DAQScanSession))
            
            
            if (coordInicialX >= 0 && coordInicialY >= 0) && (coordFinalX <= 80 && coordFinalY <= 80)
                
                % Transformar posiciones iniciales a voltaje
                voltInicialX = coordInicialX/8;
                voltInicialY = coordInicialY/8;
                
                % Transformar posiciones finales a voltaje
                voltFinalX = coordFinalX/8;
                voltFinalY = coordFinalY/8;
                
                % Setear voltajes a los que iremos en el pto inicial 
                app.voltX = voltInicialX;
                app.voltY = voltInicialY;
                
                disp(4)
                disp(class(app.DAQScanSession))
                % Desplazarse a las coordenadas iniciales ingresadas
                desplazarScan(app)
                disp(5)
                disp(class(app.DAQScanSession))
                
                
                disp(6)
                % Una vez adquiridos datos totales se activará listener
                app.DAQScanSession.NotifyWhenDataAvailableExceeds = totalElementosOutput;
                
                % Arreglo de voltajes a seguir en eje X
                voltPathX = linspace(voltInicialX, voltFinalX, nPuntos)';
                
                % Arreglo de voltajes a seguir en eje Y
                voltPathY = linspace(voltInicialY, voltFinalY, nPuntos)';
                
                % Guardar caminos de voltaje en coordenadas
                app.xCoordsZoom = voltPathX * 8;
                app.yCoordsZoom = voltPathY * 8;
                
                % Voltaje de ida en X
                subidaVoltX = repelem(voltPathX, constVoltIntervalo);
                
                % Voltaje de vuelta en X
                bajadaVoltX = flip(voltPathX);
                % Repetir primer y ultimo elemento en bajada de voltaje
                repUltElemento = repelem(bajadaVoltX(end),tiempoEsperaVueltaOrigen)';
                
                bajadaVoltX = [bajadaVoltX;repUltElemento];
                
                % Vector con voltajes concatenados
                outVoltajeX = [subidaVoltX; bajadaVoltX];
                
                % Matriz con total de voltajes para X
                outVoltajeX = repmat(outVoltajeX, nPuntos, 1);
                
                % Periodo voltaje X
                periodoVoltX = length(outVoltajeX)/nPuntos;
                
                % Output de voltajes a entregar en el piezo en dimension Y
                outVoltajeY = repelem(voltPathY, periodoVoltX);
                
                disp(length(outVoltajeY))
                disp(length(outVoltajeX))
                
                
                disp(7)
                disp(class(app.DAQScanSession))
                % Poner voltajes a la cola
                
                %============================================================================
                %====================== P A R A M.  C U E N T A S ===========================
                
                % Elementos en cada intervalo de settling time
                intervaloSettlingTime = int64(settlingTime * app.ScanFreqEditField.Value);
                
                % Elementos en intervalo de cuentas
                intervaloCuentas = int64(integrationTime * app.ScanFreqEditField.Value);
                
                % Cantidad de elementos hasta que X vuelve al 0
                periodoIntervalo = (intervaloSettlingTime + intervaloCuentas) * nPuntos;
                
                % indices a eliminar; donde se vuelve al inicio
                initNoCtsIdx = [periodoIntervalo + 1:periodoVoltX:totalElementosOutput]';
                
                % Indice donde X termina de volver al 0
                finNoCtsIdx = [periodoVoltX:periodoVoltX:totalElementosOutput]';
                
                % Indices concatenados a eliminar
                idxNoCts = [initNoCtsIdx finNoCtsIdx];
                
                % Dar vuelta los indices para eliminar desde el indice mayor
                idxNoCts = flip(idxNoCts);
                
                clear initNoCtsIdx finNoCtsIdx periodoIntervalo
                
                totalElemCts = (constVoltIntervalo) * nPuntos * nPuntos;
                
                % Intervalos donde estan las cuentas
                initCtsIdx = [intervaloSettlingTime:constVoltIntervalo:totalElemCts]';
                finCtsIdx = [constVoltIntervalo-1:constVoltIntervalo:totalElemCts]';
                
                idxCts = [initCtsIdx finCtsIdx];
                
                clear initCtsIdx finCtsIdx
                
                % Crear el listener para generar matriz
                app.scanListener = addlistener(app.DAQScanSession, 'DataAvailable', ...
                    @(src, event) scanProcessData(app, event, idxNoCts, idxCts, 'zoom'));
                
                queueOutputData(app.DAQScanSession,[outVoltajeX, outVoltajeY]);
                pause(1)
                startBackground(app.DAQScanSession);


            else
                beep
                errordlg('Scan out of range! Please adjust the coordinate values.', 'Error');
            end
            
        end

        % Value changed function: SetFigButton
        function SetFigButtonValueChanged(app, event)
            app.countsMatrix = app.countsZoom;
            app.xCoordCts = app.xCoordsZoom;
            app.yCoordCts = app.yCoordsZoom;
            
        end

        % Value changed function: PrevFigButton
        function PrevFigButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQScanSession))
                
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
                
                actualizarVoltajes(app)
                
                setAppViewState(app, 'configuration')
            end
            
            dataLoadedCallback(app)
        end

        % Value changed function: SavezoomButton
        function SavezoomButtonValueChanged(app, event)
            guardarDatosScan(app,'zoom')
            
        end

        % Value changed function: StartOptButton
        function StartOptButtonValueChanged(app, event)
            
            % Eliminar ejes gráficos de mapa PL
            delete(app.hXLine)
            delete(app.hYLine)
            %===========================================================
            
            % Verificar que coordenadas esten desacopladas
            if ~(isempty(app.DAQSesionCoordX))
                
                disp(app.DAQSesionCoordX.Rate)
                
                % Leer voltajes actuales en piezo
                actualizarVoltajes(app);
                
                % Obtener coordenadas actuales
                x = app.currentVoltX * 8;
                y = app.currentVoltY * 8;
                z = app.currentVoltZ * 8;
                
                % Generar coordenadas a visitar en optimizacion
                posicionesX = vectorCoordsOptimiza(app, x, app.XYRangeEditField.Value, 100);
                posicionesY = vectorCoordsOptimiza(app, y, app.XYRangeEditField.Value, 100);
                posicionesZ = vectorCoordsOptimiza(app, z, app.ZRangeEditField.Value, 100);
            
                
                assignin("base",'posicionesX', posicionesX)
                
                
                %=========================================================================
                %============================= O P T .  X ================================
                
                % Voltajes de salida
                outVoltX = posicionesX*0.125;
                
                assignin('base',"outVoltX",outVoltX)
                
                % Mover el piezo en la coordenada X al punto de partida
                app.voltX = outVoltX(1);
                desplazarCoordenada(app,'x')
                
                % Configurar rate
                rateTrazaEff = app.IntTimemsEditField.Value/1000 * app.NotifyScansSpinner.Value;
                stop(app.DAQSesionCoordX)
                app.DAQSesionCoordX.Rate = ceil(1/rateTrazaEff);
                disp(app.DAQSesionCoordX.Rate)
                % Configurar acumulacion de cuentas
                app.acumularCtas = true;
                
                
                % Mover piezo y recibir cuentas
                queueOutputData(app.DAQSesionCoordX,outVoltX)
                app.DAQSesionCoordX.startForeground();
                app.acumularCtas = false;
                
                % Descartar primeras 50 cuentas
                %app.ctasAcumOpt = app.ctasAcumOpt(51:end);
                % Guardar cuentas en workspace
                assignin('base','ctasAcumuladasX',app.ctasAcumuladasOpt);
                
                % Obtener posición y anchura ajustadas
                disp(length(app.ctasAcumuladasOpt)-length(posicionesX)+1)
                cuentasX = app.ctasAcumuladasOpt(length(app.ctasAcumuladasOpt)-length(posicionesX)+1:end);
                assignin('base','ctasX',cuentasX)
                
                [posAjustadaX, anchuraAjustadaX] = optimizarPos(app,posicionesX,cuentasX, 0.45)
                
                 % Moverse desde pos. actual a pos optima X
                app.voltX = posAjustadaX * 0.125;
                desplazarCoordenada(app, 'x')
                
                % Actualizar GUI
                hold(app.UIAxes, 'on');
                app.hXLine = xline(app.UIAxes, posAjustadaX, 'k');
                hold(app.UIAxes, 'off');
                
                app.XcoordEditField.Value = posAjustadaX;
                
                % Eliminar cuentas acumuladas de la app
                app.ctasAcumuladasOpt = [];
                
                stop(app.DAQSesionCoordX)
                app.DAQSesionCoordX.Rate = 1000;
                disp(app.DAQSesionCoordX.Rate)
                
                %=========================================================================
                %============================= O P T .  Y ================================
                
                % Voltajes de salida
                %outVoltX = repelem(posicionesX*0.125, constVoltIntervalo);
                outVoltY = posicionesY*0.125;
                
                assignin('base',"outVoltY",outVoltY)
                
                % Mover el piezo en la coordenada X al punto de partida
                app.voltY = outVoltY(1);
                desplazarCoordenada(app,'y')
                
                % Configurar rate
                
                stop(app.DAQSesionCoordY)
                app.DAQSesionCoordY.Rate = ceil(1/rateTrazaEff);
                disp(app.DAQSesionCoordY.Rate)
                % Configurar acumulacion de cuentas
                app.acumularCtas = true;
                
                
                % Mover piezo y recibir cuentas
                queueOutputData(app.DAQSesionCoordY,outVoltY)
                app.DAQSesionCoordY.startForeground();
                app.acumularCtas = false;
                
                % Guardar cuentas en workspace
                assignin('base','ctasAcumuladasY',app.ctasAcumuladasOpt);
                
                % Obtener posición y anchura ajustadas
                disp(length(app.ctasAcumuladasOpt)-length(posicionesY)+1)
                cuentasY = app.ctasAcumuladasOpt(length(app.ctasAcumuladasOpt)-length(posicionesY)+1:end);
                assignin('base','ctasY',cuentasY)
                
                [posAjustadaY, anchuraAjustadaY] = optimizarPos(app,posicionesY,cuentasY, 0.45)
                
                 % Moverse desde pos. actual a pos optima X
                app.voltY = posAjustadaY * 0.125;
                desplazarCoordenada(app, 'y')
                
                % Actualizar GUI
                hold(app.UIAxes, 'on');
                app.hYLine = yline(app.UIAxes, posAjustadaY, 'k');
                hold(app.UIAxes, 'off');
                
                app.YcoordEditField.Value = posAjustadaY;
                
                % Eliminar cuentas acumuladas de la app
                app.ctasAcumuladasOpt = [];
                
                stop(app.DAQSesionCoordY)
                app.DAQSesionCoordY.Rate = 1000;
                disp(app.DAQSesionCoordY.Rate)
                
                %=========================================================================
                %============================= O P T .  Z ================================
                
                % Voltajes de salida
                outVoltZ = posicionesZ*0.125;
                
                assignin('base',"outVoltZ",outVoltZ)
                
                % Mover el piezo en la coordenada X al punto de partida
                app.voltZ = outVoltZ(1);
                desplazarCoordenada(app,'z')
                
                % Configurar rate
                
                stop(app.DAQSesionCoordZ)
                app.DAQSesionCoordZ.Rate = ceil(1/rateTrazaEff);
                disp(app.DAQSesionCoordZ.Rate)
                % Configurar acumulacion de cuentas
                app.acumularCtas = true;
                
                
                % Mover piezo y recibir cuentas
                queueOutputData(app.DAQSesionCoordZ,outVoltZ)
                app.DAQSesionCoordZ.startForeground();
                app.acumularCtas = false;
                
                % Guardar cuentas en workspace
                assignin('base','ctasAcumuladasZ',app.ctasAcumuladasOpt);
                
                % Obtener posición y anchura ajustadas
                disp(length(app.ctasAcumuladasOpt)-length(posicionesZ)+1)
                cuentasZ = app.ctasAcumuladasOpt(length(app.ctasAcumuladasOpt)-length(posicionesZ)+1:end);
                assignin('base','ctasZ',cuentasZ)
                
                [posAjustadaZ, anchuraAjustadaZ] = optimizarPos(app,posicionesZ,cuentasZ, 0.45)
                
                 % Moverse desde pos. actual a pos optima X
                app.voltZ = posAjustadaZ * 0.125;
                desplazarCoordenada(app, 'z')
                
                
                app.ZcoordEditField.Value = posAjustadaZ;
                
                % Eliminar cuentas acumuladas de la app
                app.ctasAcumuladasOpt = [];
                
                stop(app.DAQSesionCoordZ)
                app.DAQSesionCoordZ.Rate = 1000;
                disp(app.DAQSesionCoordZ.Rate)

                
            else
                iniciarSesionCoordenada(app,'x')
                iniciarSesionCoordenada(app,'y')
                iniciarSesionCoordenada(app,'z')
            end


        end

        % Value changed function: CleanFigButton
        function CleanFigButtonValueChanged(app, event)
            app.countsMatrix = zeros(80,80);
            app.xCoordCts = linspace(0,80,81)';
            app.yCoordCts = linspace(0,80,81)';
            
            dataLoadedCallback(app)
            
            actualizarBarraColor(app,'scan')
        end

        % Value changed function: TrackerSwitch
        function TrackerSwitchValueChanged(app, event)
            value = app.TrackerSwitch.Value;
            switch value
                case 'Off'
                    app.trackerReady = false;
                case 'On'
                    app.trackerReady = true;
            end
        end

        % Callback function
        function Traza2TestButtonPushed(app, event)
            
            %======================================================================
            %===================== CONF. GRÁFICO TRAZA ======================
            
            % Setear buffers como arreglos vacíos previo a usarlos
            app.DataFIFOBufferTrazaScan = [];
            app.TimestampsFIFOBufferTrazaScan = [];
            
            
            % Crear figura externa a la app para mostrar traza
            app.traceScanFigure = figure('Name','Traza Scan', 'Position', [100, 100, 800, 600]);
            
            % Obtener el handle a los ejes de la figura
            app.axesScanTrace = axes(app.traceScanFigure);
            %Hacer plot sin datos
            app.lineScanTrace = plot(app.axesScanTrace, NaN, 'LineWidth',3,"Color",'k');
            
            % Conf. título y etiquetas
            title(app.axesScanTrace, 'Traza Scan');
            xlabel(app.axesScanTrace, 'u.a');
            ylabel(app.axesScanTrace, 'cuentas/s');
            
            xlim(app.axesScanTrace,[0, app.NBufferEditField.Value]);
            set(app.axesScanTrace, 'FontSize', 32);
            
            %======================================================================
            %======================================================================
            % Iniciar sesion de traza scan
            iniciarTrazaScan(app)
            
            % Crear listener e iniciar en 2do plano sesion de adquisición
            app.trazaScanListener = addlistener(app.DAQTrazaScan, 'DataAvailable', ...
                @(src,event) trazaScanCallback(app, src, event));
            
            startBackground(app.DAQTrazaScan);
            %======================================================================
            %======================================================================
            
        end

        % Callback function
        function StopTraza2ButtonValueChanged(app, event)
            % Detener adquisición de datos
            stop(app.DAQTrazaScan)
            
            % Eliminar e inicializar listener a array vacío
            delete(app.trazaScanListener);
            app.trazaScanListener = [];
            
            % Setear handles de gráfico de traza a array vacios
            delete(app.traceScanFigure)
            app.traceScanFigure = [];
            app.axesScanTrace = [];
            app.lineScanTrace = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LiveViewPanel = uipanel(app.GridLayout);
            app.LiveViewPanel.Title = 'Panel';
            app.UIAxes = uiaxes(app.LiveViewPanel);
            title(app.UIAxes, 'PL MAP')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PanelTraza = uipanel(app.GridLayout);
            app.PanelTraza.Title = 'Panel2';
            app.StartTraceButton = uibutton(app.PanelTraza, 'push');
            app.StartTraceButton.ButtonPushedFcn = createCallbackFcn(app, @StartTraceButtonPushed, true);
            app.StartTraceButton.Text = 'Start Trace';
            app.StopTraceButton = uibutton(app.PanelTraza, 'state');
            app.StopTraceButton.ValueChangedFcn = createCallbackFcn(app, @StopTraceButtonValueChanged, true);
            app.StopTraceButton.Text = 'Stop Trace';
            app.IntTimemsEditFieldLabel = uilabel(app.PanelTraza);
            app.IntTimemsEditFieldLabel.Text = 'Int. Time (ms)';
            app.IntTimemsEditField = uieditfield(app.PanelTraza, 'numeric');
            app.IntTimemsEditField.Limits = [0.0005 1000];
            app.IntTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @IntTimemsEditFieldValueChanged, true);
            app.IntTimemsEditField.Value = 2;
            app.NBufferEditFieldLabel = uilabel(app.PanelTraza);
            app.NBufferEditFieldLabel.Text = 'N Buffer';
            app.NBufferEditField = uieditfield(app.PanelTraza, 'numeric');
            app.NBufferEditField.Limits = [25 1000];
            app.NBufferEditField.ValueDisplayFormat = '%.0f';
            app.NBufferEditField.ValueChangedFcn = createCallbackFcn(app, @NBufferEditFieldValueChanged, true);
            app.NBufferEditField.Value = 50;
            app.NotifyScansSpinnerLabel = uilabel(app.PanelTraza);
            app.NotifyScansSpinnerLabel.Text = 'Notify Scans';
            app.NotifyScansSpinner = uispinner(app.PanelTraza);
            app.NotifyScansSpinner.Limits = [25 50000];
            app.NotifyScansSpinner.ValueDisplayFormat = '%g';
            app.NotifyScansSpinner.ValueChangedFcn = createCallbackFcn(app, @NotifyScansSpinnerValueChanged, true);
            app.NotifyScansSpinner.Value = 50;
            app.MeanEditFieldLabel = uilabel(app.PanelTraza);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.PanelTraza, 'numeric');
            app.MeanEditField.ValueDisplayFormat = '%.2e';
            app.MeanEditField.Editable = 'off';
            app.STDEditFieldLabel = uilabel(app.PanelTraza);
            app.STDEditFieldLabel.Text = 'STD';
            app.STDEditField = uieditfield(app.PanelTraza, 'numeric');
            app.STDEditField.ValueDisplayFormat = '%.2e';
            app.STDEditField.Editable = 'off';
            app.SavingDataSwitchLabel = uilabel(app.PanelTraza);
            app.SavingDataSwitchLabel.Text = 'Saving Data';
            app.SavingDataSwitch = uiswitch(app.PanelTraza, 'slider');
            app.SavingDataSwitch.ValueChangedFcn = createCallbackFcn(app, @SavingDataSwitchValueChanged, true);
            app.Panel4 = uipanel(app.GridLayout);
            app.Panel4.Title = 'Panel4';
            app.ScanButton = uibutton(app.Panel4, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ScanSizeumEditFieldLabel = uilabel(app.Panel4);
            app.ScanSizeumEditFieldLabel.Text = 'Scan Size (um)';
            app.ScanSizeumEditField = uieditfield(app.Panel4, 'numeric');
            app.ScanSizeumEditField.Limits = [2 100];
            app.ScanSizeumEditField.Value = 50;
            app.ScanFreqEditFieldLabel = uilabel(app.Panel4);
            app.ScanFreqEditFieldLabel.Text = 'Scan Freq.';
            app.ScanFreqEditField = uieditfield(app.Panel4, 'numeric');
            app.ScanFreqEditField.Limits = [100 5000000];
            app.ScanFreqEditField.ValueDisplayFormat = '%.2e';
            app.ScanFreqEditField.ValueChangedFcn = createCallbackFcn(app, @ScanNpixelsEditFieldValueChanged, true);
            app.ScanFreqEditField.Value = 50000;
            app.XoffsetEditFieldLabel = uilabel(app.Panel4);
            app.XoffsetEditFieldLabel.Text = 'X offset';
            app.XoffsetEditField = uieditfield(app.Panel4, 'numeric');
            app.XoffsetEditField.Limits = [0 80];
            app.XoffsetEditField.ValueDisplayFormat = '%.3f';
            app.XoffsetEditField.Value = 40;
            app.YoffsetEditFieldLabel = uilabel(app.Panel4);
            app.YoffsetEditFieldLabel.Text = 'Y offset';
            app.YoffsetEditField = uieditfield(app.Panel4, 'numeric');
            app.YoffsetEditField.Limits = [0 80];
            app.YoffsetEditField.ValueDisplayFormat = '%.3f';
            app.YoffsetEditField.Value = 40;
            app.StopscanButton = uibutton(app.Panel4, 'push');
            app.StopscanButton.ButtonPushedFcn = createCallbackFcn(app, @StopscanButtonPushed, true);
            app.StopscanButton.Text = 'Stop scan';
            app.ScanNpixelsEditFieldLabel = uilabel(app.Panel4);
            app.ScanNpixelsEditFieldLabel.Text = 'Scan N. pixels';
            app.ScanNpixelsEditField = uieditfield(app.Panel4, 'numeric');
            app.ScanNpixelsEditField.Limits = [1 1000];
            app.ScanNpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @ScanNpixelsEditFieldValueChanged, true);
            app.ScanNpixelsEditField.Value = 30;
            app.CtimemsEditFieldLabel = uilabel(app.Panel4);
            app.CtimemsEditFieldLabel.Text = 'C. time (ms)';
            app.CtimemsEditField = uieditfield(app.Panel4, 'numeric');
            app.CtimemsEditField.Limits = [0.0005 1000];
            app.CtimemsEditField.ValueChangedFcn = createCallbackFcn(app, @ScanNpixelsEditFieldValueChanged, true);
            app.CtimemsEditField.Value = 1;
            app.StimemsEditFieldLabel = uilabel(app.Panel4);
            app.StimemsEditFieldLabel.Text = 'S. time (ms)';
            app.StimemsEditField = uieditfield(app.Panel4, 'numeric');
            app.StimemsEditField.Limits = [0.0005 1000];
            app.StimemsEditField.ValueChangedFcn = createCallbackFcn(app, @ScanNpixelsEditFieldValueChanged, true);
            app.StimemsEditField.Value = 2;
            app.SaveScanButton = uibutton(app.Panel4, 'push');
            app.SaveScanButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScanButtonPushed, true);
            app.SaveScanButton.Text = 'Save Scan';
            app.SampleNameEditFieldLabel = uilabel(app.Panel4);
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.SampleNameEditField = uieditfield(app.Panel4, 'text');
            app.SampleNameEditField.Value = 'sampleName';
            app.FilterNameEditFieldLabel = uilabel(app.Panel4);
            app.FilterNameEditFieldLabel.Text = 'Filter Name';
            app.FilterNameEditField = uieditfield(app.Panel4, 'text');
            app.FilterNameEditField.Value = 'BLP01-532R';
            app.DetectorEditFieldLabel = uilabel(app.Panel4);
            app.DetectorEditFieldLabel.Text = 'Detector';
            app.DetectorEditField = uieditfield(app.Panel4, 'text');
            app.DetectorEditField.Value = 'APD';
            app.PoweruWEditFieldLabel = uilabel(app.Panel4);
            app.PoweruWEditFieldLabel.Text = 'Power (uW)';
            app.PoweruWEditField = uieditfield(app.Panel4, 'numeric');
            app.PoweruWEditField.Value = 2;
            app.WavelengthnmEditFieldLabel = uilabel(app.Panel4);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.Panel4, 'numeric');
            app.WavelengthnmEditField.Value = 532;
            app.RemainingTimeScansEditFieldLabel = uilabel(app.Panel4);
            app.RemainingTimeScansEditFieldLabel.Text = 'Remaining Time Scan (s)';
            app.RemainingTimeScansEditField = uieditfield(app.Panel4, 'numeric');
            app.RemainingTimeScansEditField.Editable = 'off';
            app.ZoffsetEditFieldLabel = uilabel(app.Panel4);
            app.ZoffsetEditFieldLabel.Text = 'Z offset';
            app.ZoffsetEditField = uieditfield(app.Panel4, 'numeric');
            app.ZoffsetEditField.Limits = [0 80];
            app.ZoffsetEditField.ValueDisplayFormat = '%.3f';
            app.ZoffsetEditField.Value = 40;
            app.Panel5 = uipanel(app.GridLayout);
            app.Panel5.Title = 'Panel5';
            app.UpYButton = uibutton(app.Panel5, 'state');
            app.UpYButton.ValueChangedFcn = createCallbackFcn(app, @UpYButtonValueChanged, true);
            app.UpYButton.Text = 'Up Y';
            app.DownYButton = uibutton(app.Panel5, 'state');
            app.DownYButton.ValueChangedFcn = createCallbackFcn(app, @DownYButtonValueChanged, true);
            app.DownYButton.Text = 'Down Y';
            app.RightXButton = uibutton(app.Panel5, 'state');
            app.RightXButton.ValueChangedFcn = createCallbackFcn(app, @RightXButtonValueChanged, true);
            app.RightXButton.Text = 'Right X';
            app.LeftXButton = uibutton(app.Panel5, 'state');
            app.LeftXButton.ValueChangedFcn = createCallbackFcn(app, @LeftXButtonValueChanged, true);
            app.LeftXButton.Text = 'Left X';
            app.DecZButton = uibutton(app.Panel5, 'state');
            app.DecZButton.ValueChangedFcn = createCallbackFcn(app, @DecZButtonValueChanged, true);
            app.DecZButton.Text = 'Dec. Z';
            app.IncZButton = uibutton(app.Panel5, 'state');
            app.IncZButton.ValueChangedFcn = createCallbackFcn(app, @IncZButtonValueChanged, true);
            app.IncZButton.Text = 'Inc. Z';
            app.MovenmEditFieldLabel = uilabel(app.Panel5);
            app.MovenmEditFieldLabel.Text = {'Move (nm)'; ''};
            app.MovenmEditField = uieditfield(app.Panel5, 'numeric');
            app.MovenmEditField.Limits = [0 10000];
            app.MovenmEditField.ValueDisplayFormat = '%11.3g';
            app.MovenmEditField.Value = 30;
            app.MaxZEditFieldLabel = uilabel(app.Panel5);
            app.MaxZEditFieldLabel.Text = 'Max. Z';
            app.MaxZEditField = uieditfield(app.Panel5, 'numeric');
            app.MaxZEditField.Limits = [0 80];
            app.MaxZEditField.ValueChangedFcn = createCallbackFcn(app, @MaxZEditFieldValueChanged, true);
            app.MaxZEditField.Value = 80;
            app.MinZEditFieldLabel = uilabel(app.Panel5);
            app.MinZEditFieldLabel.Text = 'Min. Z';
            app.MinZEditField = uieditfield(app.Panel5, 'numeric');
            app.MinZEditField.Limits = [0 80];
            app.MinZEditField.ValueChangedFcn = createCallbackFcn(app, @MinZEditFieldValueChanged, true);
            app.ActPanelButton = uibutton(app.Panel5, 'push');
            app.ActPanelButton.ButtonPushedFcn = createCallbackFcn(app, @ActPanelButtonPushed, true);
            app.ActPanelButton.Text = 'Act. Panel';
            app.Goto000Button = uibutton(app.Panel5, 'state');
            app.Goto000Button.ValueChangedFcn = createCallbackFcn(app, @Goto000ButtonValueChanged, true);
            app.Goto000Button.Text = 'Go to (0, 0, 0)';
            app.ZSliderLabel = uilabel(app.Panel5);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.Panel5);
            app.ZSlider.Limits = [0 80];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.Panel6 = uipanel(app.GridLayout);
            app.Panel6.Title = 'Panel6';
            app.XcoordEditFieldLabel = uilabel(app.Panel6);
            app.XcoordEditFieldLabel.Text = 'X coord.';
            app.XcoordEditField = uieditfield(app.Panel6, 'numeric');
            app.XcoordEditField.ValueDisplayFormat = '%.3f';
            app.XcoordEditField.Editable = 'off';
            app.YcoordEditFieldLabel = uilabel(app.Panel6);
            app.YcoordEditFieldLabel.Text = 'Y coord.';
            app.YcoordEditField = uieditfield(app.Panel6, 'numeric');
            app.YcoordEditField.ValueDisplayFormat = '%.3f';
            app.YcoordEditField.Editable = 'off';
            app.ZcoordEditFieldLabel = uilabel(app.Panel6);
            app.ZcoordEditFieldLabel.Text = 'Z coord.';
            app.ZcoordEditField = uieditfield(app.Panel6, 'numeric');
            app.ZcoordEditField.ValueDisplayFormat = '%.3f';
            app.ZcoordEditField.Editable = 'off';
            app.Panel7 = uipanel(app.GridLayout);
            app.Panel7.Title = 'Panel7';
            app.LoadFigureButton = uibutton(app.Panel7, 'push');
            app.LoadFigureButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFigureButtonPushed, true);
            app.LoadFigureButton.Text = 'Load Figure';
            app.SetoffsetButton = uibutton(app.Panel7, 'state');
            app.SetoffsetButton.ValueChangedFcn = createCallbackFcn(app, @SetoffsetButtonValueChanged, true);
            app.SetoffsetButton.Text = 'Set offset';
            app.ZoomButton = uibutton(app.Panel7, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Text = 'Zoom';
            app.SetFigButton = uibutton(app.Panel7, 'state');
            app.SetFigButton.ValueChangedFcn = createCallbackFcn(app, @SetFigButtonValueChanged, true);
            app.SetFigButton.Text = 'Set Fig.';
            app.PrevFigButton = uibutton(app.Panel7, 'state');
            app.PrevFigButton.ValueChangedFcn = createCallbackFcn(app, @PrevFigButtonValueChanged, true);
            app.PrevFigButton.Text = 'Prev. Fig.';
            app.MinColorbarEditFieldLabel = uilabel(app.Panel7);
            app.MinColorbarEditFieldLabel.Text = 'Min. Colorbar';
            app.MinColorbarEditField = uieditfield(app.Panel7, 'numeric');
            app.MinColorbarEditField.ValueDisplayFormat = '%.2e';
            app.MinColorbarEditField.ValueChangedFcn = createCallbackFcn(app, @MinColorbarEditFieldValueChanged, true);
            app.MaxColorbarEditFieldLabel = uilabel(app.Panel7);
            app.MaxColorbarEditFieldLabel.Text = 'Max. Colorbar';
            app.MaxColorbarEditField = uieditfield(app.Panel7, 'numeric');
            app.MaxColorbarEditField.ValueDisplayFormat = '%.2e';
            app.MaxColorbarEditField.ValueChangedFcn = createCallbackFcn(app, @MaxColorbarEditFieldValueChanged, true);
            app.SavezoomButton = uibutton(app.Panel7, 'state');
            app.SavezoomButton.ValueChangedFcn = createCallbackFcn(app, @SavezoomButtonValueChanged, true);
            app.SavezoomButton.Text = 'Save zoom';
            app.ZoomSizeumEditFieldLabel = uilabel(app.Panel7);
            app.ZoomSizeumEditFieldLabel.Text = 'Zoom Size (um)';
            app.ZoomSizeumEditField = uieditfield(app.Panel7, 'numeric');
            app.ZoomSizeumEditField.Limits = [1 30];
            app.ZoomSizeumEditField.Value = 2;
            app.CleanFigButton = uibutton(app.Panel7, 'state');
            app.CleanFigButton.ValueChangedFcn = createCallbackFcn(app, @CleanFigButtonValueChanged, true);
            app.CleanFigButton.Text = 'Clean Fig.';
            app.Panel3 = uipanel(app.GridLayout);
            app.Panel3.Title = 'Panel3';
            app.TrackerSwitchLabel = uilabel(app.Panel3);
            app.TrackerSwitchLabel.Text = 'Tracker';
            app.TrackerSwitch = uiswitch(app.Panel3, 'slider');
            app.TrackerSwitch.ValueChangedFcn = createCallbackFcn(app, @TrackerSwitchValueChanged, true);
            app.StartOptButton = uibutton(app.Panel3, 'state');
            app.StartOptButton.ValueChangedFcn = createCallbackFcn(app, @StartOptButtonValueChanged, true);
            app.StartOptButton.Text = 'Start Opt.';
            app.XYRangeEditFieldLabel = uilabel(app.Panel3);
            app.XYRangeEditFieldLabel.Text = 'X-Y Range';
            app.XYRangeEditField = uieditfield(app.Panel3, 'numeric');
            app.XYRangeEditField.Value = 800;
            app.ZRangeEditFieldLabel = uilabel(app.Panel3);
            app.ZRangeEditFieldLabel.Text = 'Z Range';
            app.ZRangeEditField = uieditfield(app.Panel3, 'numeric');
            app.ZRangeEditField.Value = 1800;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQDataAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end